classdef P2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        sliderTiempo         matlab.ui.control.Slider
        FrecuenciaLabel_2    matlab.ui.control.Label
        sliderFrec           matlab.ui.control.Slider
        FrecuenciaLabel      matlab.ui.control.Label
        sliderAmplitud       matlab.ui.control.Slider
        AmplitudSliderLabel  matlab.ui.control.Label
        downSignal           matlab.ui.control.DropDown
        SealDropDownLabel    matlab.ui.control.Label
        txtTitulo            matlab.ui.control.Label
        btnGraficar          matlab.ui.control.Button
        groupRep             matlab.ui.container.ButtonGroup
        radioDisc            matlab.ui.control.RadioButton
        radioCont            matlab.ui.control.RadioButton
        axes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnGraficar
        function btnGraficarPushed(app, event)
            signal = app.downSignal.Value;
            amplitud = app.sliderAmplitud.Value;
            frecuencia = app.sliderFrec.Value;
            tiempo = app.sliderTiempo.Value;

            if signal == 2 %% Senoidal
                if app.radioCont.Value == 1
                    %% Continuo
                    % Vector de tiempo
                    t = linspace(0, tiempo, 1000); % 1000 puntos de tiempo
                    x = amplitud * sin(2 * pi * frecuencia * t);
                    
                    plot(app.axes,t, x);
                    app.axes.Title.String ='Senoidal Continuo'; 
                elseif app.radioDisc.Value == 1 
                    %% Discreto
                    A = amplitud; %Amplitud
                    f = frecuencia/10; %Frecuencia Fundamental
                    phi = pi/2; %Desfase en radianes
                    T = tiempo*(1/f); %Número de Periodos a Graficar
                    n = 0:T; %Tiempo de Simulación
                    x = A*cos(2*pi*f*n-phi); %Generación de Señal
                    stem(app.axes,n,x); %Grafica
                    app.axes.Title.String ='Senoidal Discreto'; 
                end

            elseif signal == 3 %% Exponencial
               if app.radioCont.Value == 1
                   %% Continuo
                    Fs = 10000; %Frecuencia de Muestreo
                    t = 0:1/Fs:tiempo*2; %Tiempo de Simulación
                    x = amplitud.^ t;%exp(t); %Generación de Señal
                    plot(app.axes,t,x); %Grafica
                    app.axes.Title.String ='Exponencial Continuo'; 
               elseif app.radioDisc.Value == 1
                   %% Discreto
                    n = 0:tiempo*2; %Tiempo de Simulación
                    x = amplitud.^ n; %Generación de Señal
                    stem(app.axes,n,x);%Grafica
                    app.axes.Title.String ='Exponencial Discreto'; 
               end
            
            elseif signal == 4 %% Rampa
                if app.radioCont.Value == 1
                   %% Continuo
                    Fs = 10000;          % Frecuencia de Muestreo
                    t1 = 0:1/Fs:tiempo; 
                    t2 = tiempo:1/Fs:tiempo*2;
                    t = [t1,t2];         % Tiempo de Simulación
                    r = linspace(0, amplitud, length(t1));
                    x = amplitud.*[zeros(1,length(t1)),r]; % Generación de Señal
                    plot(app.axes,t,x);
                    app.axes.Title.String ='Rampa Continuo'; 
               elseif app.radioDisc.Value == 1
                   %% Discreto
                    n1 = 0:5; 
                    n2 = 5:10;
                    n = [n1,n2];         % Tiempo de Simulación
                    r = linspace(0, amplitud, length(n1));
                    r = floor(r);
                    x1 = amplitud.*[zeros(1,length(n1)),r]; % Generación de Señal
                    
                    stem(app.axes,n,x1);
                    app.axes.Title.String ='Rampa Discreto'; 
               end
            elseif signal == 5 %% Cuadrada
                if app.radioCont.Value == 1
                   %% Continuo               
                    duty = 50; %Ciclo útil
                    t = linspace(0, tiempo, 1000);
                   
                    x = amplitud*square(2*pi*frecuencia*t,duty); %Generación de Señal
                    plot(app.axes,t,x);
                    app.axes.Title.String ='Cuadrada Continuo'; 
               elseif app.radioDisc.Value == 1
                   %% Discreto
                    f = frecuencia/10; %Frecuencia Fundamental
                    T = tiempo*(1/f); %Número de Periodos a Graficar

                    duty = 50; %Ciclo útil
                    n = 0:T; %Tiempo de Simulación

                    x = amplitud*square(2*pi*f*n,duty); %Generación de Señal
                    stem(app.axes,n,x);
                    app.axes.Title.String ='Cuadrada Discreto';
               end
            elseif signal == 6 %% Triangular
                if app.radioCont.Value == 1
                   %% Continuo
                    t = linspace(0, tiempo, 1000);
                    x = amplitud*sawtooth(2*pi*t,0.5); %Generación de Señal
                    plot(app.axes,t,x); %Grafica
                    app.axes.Title.String ='Triangular Continuo'; 
               elseif app.radioDisc.Value == 1
                   %% Discreto
                    n = -tiempo:0.1:tiempo; %Tiempo de Simulación
                    x = amplitud*sawtooth(2*pi*n,0.5); %Generación de Señal
                    stem(app.axes,n,x);
                    app.axes.Title.String ='Triangular Discreto';
               end
            elseif signal == 7 %% Escalón Unitario
                if app.radioCont.Value == 1
                   %% Continuo
                    t = linspace(0, tiempo, 1000);
                    x = amplitud * heaviside(t); % Generación de la señal
                    
                    plot(app.axes,t, x); % Grafica la señal
                    app.axes.Title.String ='Escalón Unitario Continuo'; 
               elseif app.radioDisc.Value == 1
                   %% Discreto
                   n = 0:tiempo; %Tiempo de Simulación

                   x = amplitud*heaviside(n); %Generación de Señal
                   
                   stem(app.axes,n,x);
                   app.axes.Title.String ='Escalón Unitario Discreto';
               end
            elseif signal == 8 %% Impulso unitario
                %if app.radioCont.Value == 1
                   %% Continuo
                   n = 0:5; %Tiempo de Simulación
                   x = amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal
                   stem(app.axes,n,x); %Gráfica
                   app.axes.Title.String ='Impulso Unitario'; 
               %elseif app.radioDisc.Value == 1
                   %% Discreto

                 %  app.axes.Title.String ='Impulso Unitario Discreto';
                %end

            end
        end

        % Callback function
        function btnLimpiarButtonPushed(app, event)
            cla(app.axes,"reset"); 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Gráfica')
            xlabel(app.axes, 'Tiempo (s)')
            ylabel(app.axes, 'Amplitud')
            zlabel(app.axes, 'Z')
            app.groupRep = uibuttongroup(app.UIFigure);
            app.groupRep.Title = 'Representación';
            app.radioCont = uiradiobutton(app.groupRep);
            app.radioCont.Text = 'Continua';
            app.radioCont.Value = true;
            app.radioDisc = uiradiobutton(app.groupRep);
            app.radioDisc.Text = 'Discreta';
            app.btnGraficar = uibutton(app.UIFigure, 'push');
            app.btnGraficar.ButtonPushedFcn = createCallbackFcn(app, @btnGraficarPushed, true);
            app.btnGraficar.Text = 'Graficar';
            app.txtTitulo = uilabel(app.UIFigure);
            app.txtTitulo.Text = 'Generador de Señales en MatLab';
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal:';
            app.downSignal = uidropdown(app.UIFigure);
            app.downSignal.Items = {'Seleccionar', 'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalón Unitario', 'Impulso Unitario'};
            app.downSignal.ItemsData = [1 2 3 4 5 6 7 8];
            app.downSignal.Value = 1;
            app.AmplitudSliderLabel = uilabel(app.UIFigure);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.sliderAmplitud = uislider(app.UIFigure);
            app.sliderAmplitud.Limits = [0 10];
            app.sliderAmplitud.Value = 1;
            app.FrecuenciaLabel = uilabel(app.UIFigure);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.sliderFrec = uislider(app.UIFigure);
            app.sliderFrec.Limits = [0 10];
            app.sliderFrec.Value = 1;
            app.FrecuenciaLabel_2 = uilabel(app.UIFigure);
            app.FrecuenciaLabel_2.Text = 'Tiempo';
            app.sliderTiempo = uislider(app.UIFigure);
            app.sliderTiempo.Limits = [0 10];
            app.sliderTiempo.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end