classdef P3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        JustificacinTextArea            matlab.ui.control.TextArea
        JustificacinTextAreaLabel       matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        NENaturalezaestadsticaLabel     matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        VSValoresdelasealLabel          matlab.ui.control.Label
        VIVariablesIndependientesLabel  matlab.ui.control.Label
        DDimensionalidadLabel           matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NVNumerodeVariablesLabel        matlab.ui.control.Label
        CaractersticasLabel             matlab.ui.control.Label
        TiempoSlider                    matlab.ui.control.Slider
        TiempoSliderLabel               matlab.ui.control.Label
        Amplitud2graficasSlider         matlab.ui.control.Slider
        Amplitud2graficasSliderLabel    matlab.ui.control.Label
        LimpiarButton                   matlab.ui.control.Button
        AgregarButton                   matlab.ui.control.Button
        ColorDropDown                   matlab.ui.control.DropDown
        ColorDropDownLabel              matlab.ui.control.Label
        AmplitudSlider                  matlab.ui.control.Slider
        AmplitudSliderLabel             matlab.ui.control.Label
        SealDropDown                    matlab.ui.control.DropDown
        SealDropDownLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SealDropDown
        function SealDropDownValueChanged(app, event)
            index = app.SealDropDown.Value;
            nPoints = app.AmplitudSlider.Value;
            
            if index == 1
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 1;    % Amplitud de la señal
                frecuencia = 0.1;    % Frecuencia de la señal (número de ciclos por segundo)
                periodo = 1/frecuencia;    % Periodo de la señal (segundos)
                fase = pi/2;    % Fase de la señal (en radianes)
                tiempo = 0:0.01:10;    % Vector de tiempo (segundos)
                
                % Generar señal de olas
                ola = amplitud * sin(2*pi*frecuencia*tiempo + fase) + ...
                      amplitud/2 * cos(2*pi*(1/periodo)*tiempo);
                  
                % Graficar señal de olas
                plot(app.UIAxes, tiempo * nPoints, ola * index)
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Amplitud';
                app.UIAxes.Title.String = 'Señal de olas del mar (Forma/Tamaño)';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-10,10];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Vectorial"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "En general, una señal de olas de mar se puede representar como una serie temporal de alturas de ola en diferentes momentos en el tiempo, por lo que se podría considerar una señal escalar. Sin embargo, si se quiere analizar la frecuencia, la amplitud y la forma de las ondas a lo largo del tiempo, la señal tendría dimensionalidad vectorial. Además, si se quisiera analizar la dirección y la velocidad de las olas, se necesitarían más valores numéricos para describir completamente la señal, lo que también implicaría una dimensionalidad vectorial.";

                app.ColorDropDown.Value = 0;
            end

            if index == 2
                cla(app.UIAxes)
                % Definir los parámetros de la señal ECG
                fs = 200;           % Frecuencia de muestreo en Hz
                dur = 5;            % Duración de la señal en segundos
                amp = 1;            % Amplitud de la señal
                
                % Generar la señal ECG utilizando una ecuación matemática
                t = linspace(0, dur, dur*fs) * nPoints;
                ecg_signal = index * amp * (sin(2*pi*1*t) + sin(2*pi*1.25*t) + 0.5*sin(2*pi*1.5*t));
                
                % Graficar la señal ECG en un axes
                plot(app.UIAxes, t, ecg_signal);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Latidos';
                app.UIAxes.Title.String = 'Frecuencia cardíaca medida por un electrocardiograma (ECG)';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-20,20];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Vectorial"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "Una señal de electrocardiograma (ECG) es una señal vectorial, ya que se compone de múltiples valores que describen la actividad eléctrica del corazón en diferentes momentos en el tiempo. En una señal de ECG, los valores numéricos representan la actividad eléctrica del corazón en diferentes puntos en el tiempo. La señal ECG suele ser representada como una serie de ondas que corresponden a diferentes fases del ciclo cardíaco, como la contracción de las aurículas y los ventrículos, y la relajación entre contracciones. Cada una de estas ondas se puede describir como un vector de valores numéricos que representan la amplitud y la duración de la onda en diferentes puntos en el tiempo. En general, una señal ECG se compone de múltiples vectores que representan diferentes componentes de la señal en diferentes momentos en el tiempo, por lo que se considera una señal vectorial.";

                app.ColorDropDown.Value = 0;
            end

            if index == 3
                cla(app.UIAxes)
                t = 0:23; % vector de tiempo de 0 a 23 horas
                ruido_fondo = 50*ones(size(t)); % señal constante de 50 dB
                ruido_variable = (nPoints)*10*sin((pi/6)*t - pi/2) + 5; % función sinusoidal con amplitud máxima de 10 dB durante las horas pico
                ruido_total = ruido_fondo + ruido_variable; % señal final de niveles de ruido ambiental

                plot(app.UIAxes, t, ruido_total);
                app.UIAxes.XLabel.String = 'Hora del día';
                app.UIAxes.YLabel.String = 'Nivel de ruido (dB)';
                app.UIAxes.Title.String = 'Niveles de ruido ambiental en una ciudad durante el día';
                app.UIAxes.XLim = [0,23];
                app.UIAxes.YLim = [-200,200];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Escalar"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "Una señal de ruido ambiental en un día se puede considerar una señal escalar, ya que se puede describir completamente con un solo valor numérico que representa el nivel de ruido en un punto específico en el tiempo. Este valor numérico puede representar la intensidad del sonido en decibelios (dB) en un lugar específico durante un período de tiempo determinado, como en una calle con tráfico intenso, una estación de tren o un aeropuerto. Sin embargo, si se quisiera analizar la distribución del ruido en diferentes frecuencias, entonces la señal tendría una dimensionalidad vectorial, ya que requeriría múltiples valores numéricos para describir completamente la señal. En este caso, se podría utilizar un análisis espectral para representar la señal de ruido como una distribución de energía en diferentes bandas de frecuencia.";

                app.ColorDropDown.Value = 0;
            end

            if index == 4
                cla(app.UIAxes)
                fs = 1000;             % Frecuencia de muestreo
                t = 0:10/fs:10;          % Vector de tiempo
                f1 = 8;                % Frecuencia de onda alfa (en Hz)
                f2 = 15;               % Frecuencia de onda beta (en Hz)
                f3 = 30;               % Frecuencia de onda gamma (en Hz)
                amp1 = 50;             % Amplitud de la onda alfa
                amp2 = 20;             % Amplitud de la onda beta
                amp3 = 10;             % Amplitud de la onda gamma
                phase1 = pi/2;         % Fase de la onda alfa
                phase2 = pi;           % Fase de la onda beta
                phase3 = pi/4;         % Fase de la onda gamma
                signal = amp1*sin(2*pi*f1*t + phase1) + amp2*sin(2*pi*f2*t + phase2) + amp3*sin(2*pi*f3*t + phase3);
               
                plot(app.UIAxes,t, signal * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Actividad';
                app.UIAxes.Title.String = 'Señal de actividad cerebral medida por un electroencefalograma';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-300,300];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Vectorial"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "Una señal de la actividad cerebral, también conocida como electroencefalograma (EEG), se puede considerar una señal vectorial, ya que se compone de múltiples valores numéricos que representan la actividad eléctrica del cerebro en diferentes puntos en el tiempo. En una señal de EEG, los valores numéricos representan la actividad eléctrica del cerebro en diferentes regiones y en diferentes momentos en el tiempo. La señal EEG se registra mediante la colocación de electrodos en el cuero cabelludo y la medición de la actividad eléctrica del cerebro en diferentes regiones del cerebro. Cada electrodo registra una señal eléctrica que representa la actividad eléctrica del cerebro en esa región específica. Por lo tanto, la señal EEG se compone de múltiples vectores que representan diferentes componentes de la señal en diferentes momentos en el tiempo, por lo que se considera una señal vectorial. Además, si se quisiera analizar la distribución de la actividad eléctrica en diferentes frecuencias, entonces la señal tendría una dimensionalidad vectorial, ya que requeriría múltiples valores numéricos para describir completamente la señal. En este caso, se podría utilizar un análisis espectral para representar la señal de EEG como una distribución de energía en diferentes bandas de frecuencia.";

                app.ColorDropDown.Value = 0;
            end

            if index == 5
                cla(app.UIAxes)
                % Definimos los parámetros de la señal
                A = 1; % amplitud
                f = 20; % frecuencia
                t = 0:0.001:1; % vector de tiempo
                
                % Calculamos la señal
                s = A * f^2 * exp(-pi*f*t) .* sin(2*pi*f*t);
                
                % Graficamos la señal
                plot(app.UIAxes,t,s * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Magnitud';
                app.UIAxes.Title.String = 'Señal de movimiento sísmico';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-200,1000];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Vectorial"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "Una señal de movimiento sísmico se puede considerar una señal vectorial, ya que se compone de múltiples valores numéricos que representan la amplitud y la dirección del movimiento en diferentes puntos en el tiempo. En una señal de movimiento sísmico, los valores numéricos representan la amplitud y la dirección del movimiento del suelo en diferentes puntos en el tiempo. La señal se puede representar como una serie temporal de valores numéricos que representan la aceleración, la velocidad o la posición del suelo en diferentes momentos en el tiempo. Cada uno de estos valores se puede describir como un vector que tiene una magnitud (amplitud) y una dirección, lo que implica que la señal se considera una señal vectorial. Además, si se quisiera analizar la distribución de la energía sísmica en diferentes frecuencias, entonces la señal tendría una dimensionalidad vectorial, ya que requeriría múltiples valores numéricos para describir completamente la señal. En este caso, se podría utilizar un análisis espectral para representar la señal de movimiento sísmico como una distribución de energía en diferentes bandas de frecuencia.";

                app.ColorDropDown.Value = 0;
            end

            if index == 6
                cla(app.UIAxes)
                % Definir parámetros de la señal
                frecuencia = 440; % Hz
                amplitud = 1; % amplitud máxima
                duracion = 1; % duración en segundos
                fs = 44100; % frecuencia de muestreo en Hz
                
                % Calcular el número de muestras necesarias
                n_muestras = duracion * fs;
                
                % Generar vector de tiempo
                t = linspace(0, duracion, n_muestras);
                
                % Generar señal de sonido
                y = amplitud * sin(2*pi*frecuencia*t);
                
                % Graficar la señal en un axes
                plot(app.UIAxes,t, y * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Frecuencia Hz';
                app.UIAxes.Title.String = 'Señal de un sonido';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-20,20];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Escalar"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "Una señal de sonido se puede considerar una señal escalar, ya que se puede describir completamente con un solo valor numérico que representa la amplitud del sonido en un punto específico en el tiempo. Este valor numérico puede representar la presión acústica en decibelios (dB) en un lugar específico durante un período de tiempo determinado. Sin embargo, si se quisiera analizar la distribución del sonido en diferentes frecuencias, entonces la señal tendría una dimensionalidad vectorial, ya que requeriría múltiples valores numéricos para describir completamente la señal. En este caso, se podría utilizar un análisis espectral para representar la señal de sonido como una distribución de energía en diferentes bandas de frecuencia. La señal de sonido también se puede representar como una serie temporal de valores numéricos que representan la amplitud del sonido en diferentes momentos en el tiempo, lo que implicaría una dimensionalidad vectorial en el dominio temporal. En resumen, la dimensionalidad de la señal de sonido puede variar dependiendo del tipo de información que se quiera extraer de ella.";

                app.ColorDropDown.Value = 0;
            end

            if index == 7
                cla(app.UIAxes)

                A = 10;
                f = 0.5;
                phi = 0;
                B = 50;
                t = 0:0.01:10; % vector de tiempo de 0 a 10 segundos, con un intervalo de 0.01 segundos
                humedad = A*sin(2*pi*f*t + phi) + B;
                
                plot(app.UIAxes,t, humedad * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Humedad';
                app.UIAxes.Title.String = 'Señal de un Humedad';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Escalar"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "Una señal de humedad se puede considerar una señal escalar, ya que se puede describir completamente con un solo valor numérico que representa el nivel de humedad en un punto específico en el tiempo. Este valor numérico puede representar la cantidad de vapor de agua en el aire, generalmente expresado en porcentaje. Sin embargo, si se quisiera analizar la distribución de la humedad en diferentes áreas, entonces la señal tendría una dimensionalidad espacial, ya que requeriría múltiples valores numéricos para describir completamente la señal en diferentes ubicaciones. En este caso, se podría utilizar un mapa de humedad para representar la distribución de humedad en diferentes ubicaciones. También es posible que se quiera analizar la variación de la humedad en diferentes momentos en el tiempo, lo que implicaría una dimensionalidad temporal. En este caso, se podría registrar la humedad a intervalos regulares y representar la señal como una serie temporal de valores numéricos que representan el nivel de humedad en diferentes momentos en el tiempo.";

                app.ColorDropDown.Value = 0;
            end
            if index == 8
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 10; % m/s
                frecuencia = 0.1; % Hz
                
                % Tiempo de muestreo
                tiempo_total = 60; % segundos
                frecuencia_muestreo = 100; % Hz
                tiempo = linspace(0, tiempo_total, tiempo_total*frecuencia_muestreo);
                
                % Generación de la señal
                velocidad_viento = amplitud * sin(2*pi*frecuencia*tiempo);
                
                % Visualización de la señal
                plot(app.UIAxes,tiempo, velocidad_viento * nPoints);

                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Velocidad';
                app.UIAxes.Title.String = 'Velocidad del viento';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Escalar"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "Una señal de velocidad del viento se puede considerar una señal escalar, ya que se puede describir completamente con un solo valor numérico que representa la velocidad del viento en un punto específico en el tiempo. Este valor numérico puede expresarse en diferentes unidades, como metros por segundo, millas por hora o kilómetros por hora. Sin embargo, al igual que en el caso de la señal de humedad, si se quisiera analizar la distribución de la velocidad del viento en diferentes áreas, entonces la señal tendría una dimensionalidad espacial, ya que requeriría múltiples valores numéricos para describir completamente la señal en diferentes ubicaciones. En este caso, se podría utilizar un mapa de velocidad del viento para representar la distribución de la velocidad del viento en diferentes ubicaciones. También es posible que se quiera analizar la variación de la velocidad del viento en diferentes momentos en el tiempo, lo que implicaría una dimensionalidad temporal. En este caso, se podría registrar la velocidad del viento a intervalos regulares y representar la señal como una serie temporal de valores numéricos que representan la velocidad del viento en diferentes momentos en el tiempo.";

                app.ColorDropDown.Value = 0;
            end

            if index == 9
                cla(app.UIAxes)
                A = 100;         % Amplitud
                f = 1000;      % Frecuencia en Hz
                phi = 0;       % Fase inicial en radianes
                tau = 0.001;   % Constante de tiempo en segundos
                t = linspace(0, 0.01, 1000);  % Vector de tiempo de 0 a 0.01 segundos
                
                y = A * cos(2*pi*f*t + phi) .* exp(-t/tau);
                
                plot(app.UIAxes, t, y *nPoints);
                app.UIAxes.YLabel.String = 'Radiacion';
                app.UIAxes.XLabel.String = 'Distancia en Km';
                app.UIAxes.Title.String = 'Señales de Radiación Solar';
                app.UIAxes.XLim = [0,.01];
                app.UIAxes.YLim = [-500,500];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Vectorial"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "Una señal de radiación puede tener diferentes dimensionalidades dependiendo del tipo de radiación y la información que se desee extraer de ella. Si se trata de radiación ionizante, como la radiación gamma o la radiación alfa, se puede considerar una señal escalar, ya que se puede describir completamente con un solo valor numérico que representa la intensidad de la radiación en un punto específico en el tiempo. Este valor numérico se puede expresar en diferentes unidades, como Becquerelios (Bq) o Gray (Gy). Por otro lado, si se trata de radiación electromagnética, como la radiación solar o la radiación de microondas, entonces la señal puede tener una dimensionalidad vectorial, ya que se puede representar como un campo electromagnético que tiene tanto una magnitud (amplitud) como una dirección (polarización). En este caso, la señal se puede describir utilizando un conjunto de valores numéricos que representan la intensidad del campo eléctrico y magnético en diferentes frecuencias y orientaciones. En resumen, la dimensionalidad de la señal de radiación puede variar dependiendo del tipo de radiación y la información que se desee extraer de ella.";

                app.ColorDropDown.Value = 0;
            end

            if index == 10
                cla(app.UIAxes)
                % Definir los parámetros de la señal
                f = 1;  % frecuencia en Hz
                A = 10;  % amplitud en Pascal
                
                % Crear un vector de tiempo para la señal
                t = 0:0.001:10;  % vector de tiempo de 0 a 1 segundos en pasos de 1 ms
                
                % Generar la señal sinusoidal de presión
                p = A*sin(2*pi*f*t);

                plot(app.UIAxes,t, p * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Pascal';
                app.UIAxes.Title.String = 'Señal de presión';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-200,200];

                app.Label.Text = "Una variable";
                app.Label_2.Text = "Escalar"
                app.Label_3.Text = "Continuas"
                app.Label_4.Text = "Continuos"
                app.Label_5.Text = "Aleatorias"

                app.JustificacinTextArea.Value = "Una señal de presión se puede considerar una señal escalar, ya que se puede describir completamente con un solo valor numérico que representa el nivel de presión en un punto específico en el tiempo. Este valor numérico se puede expresar en diferentes unidades, como Pascal (Pa) o libras por pulgada cuadrada (psi). Sin embargo, si se quisiera analizar la distribución de la presión en diferentes áreas, entonces la señal tendría una dimensionalidad espacial, ya que requeriría múltiples valores numéricos para describir completamente la señal en diferentes ubicaciones. En este caso, se podría utilizar un mapa de presión para representar la distribución de la presión en diferentes ubicaciones. También es posible que se quiera analizar la variación de la presión en diferentes momentos en el tiempo, lo que implicaría una dimensionalidad temporal. En este caso, se podría registrar la presión a intervalos regulares y representar la señal como una serie temporal de valores numéricos que representan el nivel de presión en diferentes momentos en el tiempo.";

                app.ColorDropDown.Value = 0;
            end
            
        end

        % Value changed function: AmplitudSlider
        function AmplitudSliderValueChanged(app, event)
            index = app.SealDropDown.Value;
            nPoints = app.AmplitudSlider.Value;
            nPointsX = app.TiempoSlider.Value;


            if index == 1
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 1;    % Amplitud de la señal
                frecuencia = 0.1;    % Frecuencia de la señal (número de ciclos por segundo)
                periodo = 1/frecuencia;    % Periodo de la señal (segundos)
                fase = pi/2;    % Fase de la señal (en radianes)
                tiempo = 0:0.01:10 * (index);    % Vector de tiempo (segundos)
                
                % Generar señal de olas
                ola = (nPoints) * amplitud * sin(2*pi*frecuencia*tiempo + fase) + ...
                      amplitud/2 * cos(2*pi*(1/periodo)*tiempo);
                  
                % Graficar señal de olas
                plot(app.UIAxes, tiempo * nPointsX, ola)
                
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Amplitud';
                app.UIAxes.Title.String = 'Señal de olas del mar';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-10,10];
            end

            if index == 2
                cla(app.UIAxes)
                % Definir los parámetros de la señal ECG
                fs = 200;           % Frecuencia de muestreo en Hz
                dur = 5;            % Duración de la señal en segundos
                amp = 1;            % Amplitud de la señal
                
                % Generar la señal ECG utilizando una ecuación matemática
                t = linspace(0, dur, dur*fs) * index;
                ecg_signal = (nPoints) * amp * (sin(2*pi*1*t) + sin(2*pi*1.25*t) + 0.5*sin(2*pi*1.5*t));
                
                % Graficar la señal ECG en un axes
                plot(app.UIAxes, t * nPointsX, ecg_signal);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Latidos';
                app.UIAxes.Title.String = 'Frecuencia cardíaca medida por un electrocardiograma (ECG)';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-20,20];
            end

            if index == 3
                cla(app.UIAxes)
                t = 0:23; % vector de tiempo de 0 a 23 horas
                ruido_fondo = 50*ones(size(t)); % señal constante de 50 dB
                ruido_variable = (nPoints)*10*sin((pi/6)*t - pi/2) + 5; % función sinusoidal con amplitud máxima de 10 dB durante las horas pico
                ruido_total = ruido_fondo + ruido_variable; % señal final de niveles de ruido ambiental

                plot(app.UIAxes, t * nPointsX, ruido_total);
                app.UIAxes.XLabel.String = 'Hora del día';
                app.UIAxes.YLabel.String = 'Nivel de ruido (dB)';
                app.UIAxes.Title.String = 'Niveles de ruido ambiental en una ciudad durante el día';
                app.UIAxes.XLim = [0,23];
                app.UIAxes.YLim = [-200,200];
            end

            if index == 4
                cla(app.UIAxes)
                fs = 1000;             % Frecuencia de muestreo
                t = 0:10/fs:10;          % Vector de tiempo
                f1 = 8;                % Frecuencia de onda alfa (en Hz)
                f2 = 15;               % Frecuencia de onda beta (en Hz)
                f3 = 30;               % Frecuencia de onda gamma (en Hz)
                amp1 = 50;             % Amplitud de la onda alfa
                amp2 = 20;             % Amplitud de la onda beta
                amp3 = 10;             % Amplitud de la onda gamma
                phase1 = pi/2;         % Fase de la onda alfa
                phase2 = pi;           % Fase de la onda beta
                phase3 = pi/4;         % Fase de la onda gamma
                signal = amp1*sin(2*pi*f1*t + phase1) + amp2*sin(2*pi*f2*t + phase2) + amp3*sin(2*pi*f3*t + phase3);
               
                plot(app.UIAxes,t * nPointsX, signal * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Actividad';
                app.UIAxes.Title.String = 'Señal de actividad cerebral medida por un electroencefalograma';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-300,300];
            end

            if index == 5
                cla(app.UIAxes)
                 % Definimos los parámetros de la señal
                A = 1; % amplitud
                f = 20; % frecuencia
                t = 0:0.001:1; % vector de tiempo
                
                % Calculamos la señal
                s = A * f^2 * exp(-pi*f*t) .* sin(2*pi*f*t);
                
                % Graficamos la señal
                plot(app.UIAxes,t * nPointsX,s * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Magnitud';
                app.UIAxes.Title.String = 'Señal de movimiento sísmico';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-200,1000];
            end

            if index == 6
                cla(app.UIAxes)
                % Definir parámetros de la señal
                frecuencia = 440; % Hz
                amplitud = 1; % amplitud máxima
                duracion = 1; % duración en segundos
                fs = 44100; % frecuencia de muestreo en Hz
                
                % Calcular el número de muestras necesarias
                n_muestras = duracion * fs;
                
                % Generar vector de tiempo
                t = linspace(0, duracion, n_muestras);
                
                % Generar señal de sonido
                y = amplitud * sin(2*pi*frecuencia*t);
                
                % Graficar la señal en un axes
                plot(app.UIAxes,t * nPointsX, y * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Frecuencia Hz';
                app.UIAxes.Title.String = 'Señal de un sonido';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-20,20];
            end

            if index == 7
                cla(app.UIAxes)
                A = 10;
                f = 0.5;
                phi = 0;
                B = 50;
                t = 0:0.01:10; % vector de tiempo de 0 a 10 segundos, con un intervalo de 0.01 segundos
                humedad = A*sin(2*pi*f*t + phi) + B;
                
                plot(app.UIAxes,t * nPointsX, humedad * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Humedad';
                app.UIAxes.Title.String = 'Señal de un Humedad';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];
                
            end

            if index == 8
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 10; % m/s
                frecuencia = 0.1; % Hz
                
                % Tiempo de muestreo
                tiempo_total = 60; % segundos
                frecuencia_muestreo = 100; % Hz
                tiempo = linspace(0, tiempo_total, tiempo_total*frecuencia_muestreo);
                
                % Generación de la señal
                velocidad_viento = amplitud * sin(2*pi*frecuencia*tiempo);
                
                % Visualización de la señal
                plot(app.UIAxes,tiempo * nPointsX, velocidad_viento * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Velocidad en Km';
                app.UIAxes.Title.String = 'Velocidad del viento';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];
            end

            if index == 9
                cla(app.UIAxes)
                A = 100;         % Amplitud
                f = 1000;      % Frecuencia en Hz
                phi = 0;       % Fase inicial en radianes
                tau = 0.001;   % Constante de tiempo en segundos
                t = linspace(0, 0.01, 1000);  % Vector de tiempo de 0 a 0.01 segundos
                
                y = A * cos(2*pi*f*t + phi) .* exp(-t/tau);
                
                plot(app.UIAxes, t * nPointsX, y *nPoints);
                app.UIAxes.YLabel.String = 'Radiacion';
                app.UIAxes.XLabel.String = 'Distancia en Km';
                app.UIAxes.Title.String = 'Señales de Radiación';
                app.UIAxes.XLim = [0,.01];
                app.UIAxes.YLim = [-500,500];
            end

            if index == 10
                cla(app.UIAxes)
                % Definir los parámetros de la señal
                f = 1;  % frecuencia en Hz
                A = 10;  % amplitud en Pascal
                
                % Crear un vector de tiempo para la señal
                t = 0:0.001:10;  % vector de tiempo de 0 a 1 segundos en pasos de 1 ms
                
                % Generar la señal sinusoidal de presión
                p = A*sin(2*pi*f*t);

                plot(app.UIAxes,t * nPointsX, p * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Pascal';
                app.UIAxes.Title.String = 'Señal de presión';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-200,200];
            end
            
        end

        % Value changing function: AmplitudSlider
        function AmplitudSliderValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Callback function
        function RedButtonPushed(app, event)
            % Obtén el identificador de la línea
            linea = app.UIAxes.Children; % o usa el nombre de tu propia línea
            
            % Cambia el color de la línea a rojo
            set(linea, 'Color', 'r');

        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            
            if value == 1
                % Obtén el identificador de la línea
                linea = app.UIAxes.Children; % o usa el nombre de tu propia línea
                
                % Cambia el color de la línea a rojo
                set(linea, 'Color', 'r');
            end
            if value == 2
                % Obtén el identificador de la línea
                linea = app.UIAxes.Children; % o usa el nombre de tu propia línea
                
                % Cambia el color de la línea a rojo
                set(linea, 'Color', 'g');
            end
            if value == 3
                % Obtén el identificador de la línea
                linea = app.UIAxes.Children; % o usa el nombre de tu propia línea
                
                % Cambia el color de la línea a rojo
                set(linea, 'Color', 'y');
            end
            if value == 4
                % Obtén el identificador de la línea
                linea = app.UIAxes.Children; % o usa el nombre de tu propia línea
                
                % Cambia el color de la línea a rojo
                set(linea, 'Color', 'black');
            end
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            
            set(app.AmplitudSlider, 'Enable', 'off');
            set(app.Amplitud2graficasSlider, 'Enable', 'on');


            index = app.SealDropDown.Value;
            nPoints = app.AmplitudSlider.Value;
            
            if index == 1
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 1;    % Amplitud de la señal
                frecuencia = 0.1;    % Frecuencia de la señal (número de ciclos por segundo)
                periodo = 1/frecuencia;    % Periodo de la señal (segundos)
                fase = pi/2;    % Fase de la señal (en radianes)
                tiempo = 0:0.01:10 * (index);    % Vector de tiempo (segundos)
                
                % Generar señal de olas
                ola = (nPoints) * amplitud * sin(2*pi*frecuencia*tiempo + fase) + ...
                      amplitud/2 * cos(2*pi*(1/periodo)*tiempo);
                  
                % Graficar señal de olas
                plot(app.UIAxes, tiempo, ola)
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Amplitud';
                app.UIAxes.Title.String = 'Señal de olas del mar';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-10,10];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');

                % Traza la segunda trama
                plot(app.UIAxes, tiempo *2*nPoints, ola)
            end

            if index == 2
                cla(app.UIAxes)
                % Definir los parámetros de la señal ECG
                fs = 200;           % Frecuencia de muestreo en Hz
                dur = 5;            % Duración de la señal en segundos
                amp = 1;            % Amplitud de la señal
                
                % Generar la señal ECG utilizando una ecuación matemática
                t = linspace(0, dur, dur*fs) * index;
                ecg_signal = (2*nPoints) * amp * (sin(2*pi*1*t) + sin(2*pi*1.25*t) + 0.5*sin(2*pi*1.5*t));
                
                % Graficar la señal ECG en un axes
                plot(app.UIAxes, t, ecg_signal);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Latidos';
                app.UIAxes.Title.String = 'Frecuencia cardíaca medida por un electrocardiograma (ECG)';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-20,20];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, t*2, ecg_signal);
            end

            if index == 3
                cla(app.UIAxes)
                t = 0:23; % vector de tiempo de 0 a 23 horas
                ruido_fondo = 50*ones(size(t)); % señal constante de 50 dB
                ruido_variable = (nPoints)*10*sin((pi/6)*t - pi/2) + 5; % función sinusoidal con amplitud máxima de 10 dB durante las horas pico
                ruido_total = ruido_fondo + ruido_variable; % señal final de niveles de ruido ambiental

                plot(app.UIAxes, t, ruido_total);
                app.UIAxes.XLabel.String = 'Hora del día';
                app.UIAxes.YLabel.String = 'Nivel de ruido (dB)';
                app.UIAxes.Title.String = 'Niveles de ruido ambiental en una ciudad durante el día';
                app.UIAxes.XLim = [0,23];
                app.UIAxes.YLim = [-200,200];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, t*2, ruido_total);

            end

            if index == 4
                cla(app.UIAxes)
                fs = 1000;             % Frecuencia de muestreo
                t = 0:10/fs:10;          % Vector de tiempo
                f1 = 8;                % Frecuencia de onda alfa (en Hz)
                f2 = 15;               % Frecuencia de onda beta (en Hz)
                f3 = 30;               % Frecuencia de onda gamma (en Hz)
                amp1 = 50;             % Amplitud de la onda alfa
                amp2 = 20;             % Amplitud de la onda beta
                amp3 = 10;             % Amplitud de la onda gamma
                phase1 = pi/2;         % Fase de la onda alfa
                phase2 = pi;           % Fase de la onda beta
                phase3 = pi/4;         % Fase de la onda gamma
                signal = amp1*sin(2*pi*f1*t + phase1) + amp2*sin(2*pi*f2*t + phase2) + amp3*sin(2*pi*f3*t + phase3);
               
                plot(app.UIAxes,t, signal * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Actividad';
                app.UIAxes.Title.String = 'Señal de actividad cerebral medida por un electroencefalograma';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-300,300];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');

                plot(app.UIAxes,t *2, signal * nPoints);

            end

            if index == 5
                cla(app.UIAxes)
                 % Definimos los parámetros de la señal
                A = 1; % amplitud
                f = 20; % frecuencia
                t = 0:0.001:1; % vector de tiempo
                
                % Calculamos la señal
                s = A * f^2 * exp(-pi*f*t) .* sin(2*pi*f*t);
                
                % Graficamos la señal
                plot(app.UIAxes,t,s * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Magnitud';
                app.UIAxes.Title.String = 'Señal de movimiento sísmico';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-200,1000];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t*2,s * nPoints);

            end

            if index == 6
                cla(app.UIAxes)
                % Definir parámetros de la señal
                frecuencia = 440; % Hz
                amplitud = 1; % amplitud máxima
                duracion = 1; % duración en segundos
                fs = 44100; % frecuencia de muestreo en Hz
                
                % Calcular el número de muestras necesarias
                n_muestras = duracion * fs;
                
                % Generar vector de tiempo
                t = linspace(0, duracion, n_muestras);
                
                % Generar señal de sonido
                y = amplitud * sin(2*pi*frecuencia*t);
                
                % Graficar la señal en un axes
                plot(app.UIAxes,t, y * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Frecuencia Hz';
                app.UIAxes.Title.String = 'Señal de un sonido';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-20,20];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t*2, y * nPoints);

            end

            if index == 7
                cla(app.UIAxes)
                A = 10;
                f = 0.5;
                phi = 0;
                B = 50;
                t = 0:0.01:10; % vector de tiempo de 0 a 10 segundos, con un intervalo de 0.01 segundos
                humedad = A*sin(2*pi*f*t + phi) + B;
                
                plot(app.UIAxes,t, humedad * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Humedad';
                app.UIAxes.Title.String = 'Señal de un Humedad';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];
                
                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t*2, humedad * nPoints);

            end

            if index == 8
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 10; % m/s
                frecuencia = 0.1; % Hz
                
                % Tiempo de muestreo
                tiempo_total = 60; % segundos
                frecuencia_muestreo = 100; % Hz
                tiempo = linspace(0, tiempo_total, tiempo_total*frecuencia_muestreo);
                
                % Generación de la señal
                velocidad_viento = amplitud * sin(2*pi*frecuencia*tiempo);
                
                % Visualización de la señal
                plot(app.UIAxes,tiempo, velocidad_viento * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Velocidad en Km';
                app.UIAxes.Title.String = 'Velocidad del viento';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,tiempo*2, velocidad_viento * nPoints);

            end

            if index == 9
                cla(app.UIAxes)
                A = 100;         % Amplitud
                f = 1000;      % Frecuencia en Hz
                phi = 0;       % Fase inicial en radianes
                tau = 0.001;   % Constante de tiempo en segundos
                t = linspace(0, 0.01, 1000);  % Vector de tiempo de 0 a 0.01 segundos
                
                y = A * cos(2*pi*f*t + phi) .* exp(-t/tau);
                
                plot(app.UIAxes, t, y *nPoints);
                app.UIAxes.YLabel.String = 'Radiacion';
                app.UIAxes.XLabel.String = 'Distancia en Km';
                app.UIAxes.Title.String = 'Señales de Radiación';
                app.UIAxes.XLim = [0,.01];
                app.UIAxes.YLim = [-500,500];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, t*2, y *nPoints);

            end

            if index == 10
                cla(app.UIAxes)
                % Definir los parámetros de la señal
                f = 1;  % frecuencia en Hz
                A = 10;  % amplitud en Pascal
                
                % Crear un vector de tiempo para la señal
                t = 0:0.001:10;  % vector de tiempo de 0 a 1 segundos en pasos de 1 ms
                
                % Generar la señal sinusoidal de presión
                p = A*sin(2*pi*f*t);

                plot(app.UIAxes,t, p * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Pascal';
                app.UIAxes.Title.String = 'Señal de presión';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-200,200];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t*2, p * nPoints);

            end


        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            cla(app.UIAxes);

            set(app.AmplitudSlider, 'Enable', 'on');
            set(app.Amplitud2graficasSlider, 'Enable', 'off');
        end

        % Value changed function: Amplitud2graficasSlider
        function Amplitud2graficasSliderValueChanged(app, event)
            index = app.SealDropDown.Value;
            nPoints = app.Amplitud2graficasSlider.Value;
            
            if index == 1
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 1;    % Amplitud de la señal
                frecuencia = 0.1;    % Frecuencia de la señal (número de ciclos por segundo)
                periodo = 1/frecuencia;    % Periodo de la señal (segundos)
                fase = pi/2;    % Fase de la señal (en radianes)
                tiempo = 0:0.01:10 * (index);    % Vector de tiempo (segundos)
                
                % Generar señal de olas
                ola = (nPoints) * amplitud * sin(2*pi*frecuencia*tiempo + fase) + ...
                      amplitud/2 * cos(2*pi*(1/periodo)*tiempo);
                  
                % Graficar señal de olas
                plot(app.UIAxes, tiempo, ola)
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Amplitud';
                app.UIAxes.Title.String = 'Señal de olas del mar';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-10,10];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');

                % Traza la segunda trama
                plot(app.UIAxes, tiempo *2*nPoints, ola)
            end

            if index == 2
                cla(app.UIAxes)
                % Definir los parámetros de la señal ECG
                fs = 200;           % Frecuencia de muestreo en Hz
                dur = 5;            % Duración de la señal en segundos
                amp = 1;            % Amplitud de la señal
                
                % Generar la señal ECG utilizando una ecuación matemática
                t = linspace(0, dur, dur*fs) * index;
                ecg_signal = (2*nPoints) * amp * (sin(2*pi*1*t) + sin(2*pi*1.25*t) + 0.5*sin(2*pi*1.5*t));
                
                % Graficar la señal ECG en un axes
                plot(app.UIAxes, t, ecg_signal);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Latidos';
                app.UIAxes.Title.String = 'Frecuencia cardíaca medida por un electrocardiograma (ECG)';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-20,20];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, t*2, ecg_signal);
            end

            if index == 3
                cla(app.UIAxes)
                t = 0:23; % vector de tiempo de 0 a 23 horas
                ruido_fondo = 50*ones(size(t)); % señal constante de 50 dB
                ruido_variable = (nPoints)*10*sin((pi/6)*t - pi/2) + 5; % función sinusoidal con amplitud máxima de 10 dB durante las horas pico
                ruido_total = ruido_fondo + ruido_variable; % señal final de niveles de ruido ambiental

                plot(app.UIAxes, t, ruido_total);
                app.UIAxes.XLabel.String = 'Hora del día';
                app.UIAxes.YLabel.String = 'Nivel de ruido (dB)';
                app.UIAxes.Title.String = 'Niveles de ruido ambiental en una ciudad durante el día';
                app.UIAxes.XLim = [0,23];
                app.UIAxes.YLim = [-200,200];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, t*2, ruido_total);

            end

            if index == 4
                cla(app.UIAxes)
                fs = 1000;             % Frecuencia de muestreo
                t = 0:10/fs:10;          % Vector de tiempo
                f1 = 8;                % Frecuencia de onda alfa (en Hz)
                f2 = 15;               % Frecuencia de onda beta (en Hz)
                f3 = 30;               % Frecuencia de onda gamma (en Hz)
                amp1 = 50;             % Amplitud de la onda alfa
                amp2 = 20;             % Amplitud de la onda beta
                amp3 = 10;             % Amplitud de la onda gamma
                phase1 = pi/2;         % Fase de la onda alfa
                phase2 = pi;           % Fase de la onda beta
                phase3 = pi/4;         % Fase de la onda gamma
                signal = amp1*sin(2*pi*f1*t + phase1) + amp2*sin(2*pi*f2*t + phase2) + amp3*sin(2*pi*f3*t + phase3);
               
                plot(app.UIAxes,t, signal * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Actividad';
                app.UIAxes.Title.String = 'Señal de actividad cerebral medida por un electroencefalograma';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-300,300];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');

                plot(app.UIAxes,t *2, signal * nPoints);

            end

            if index == 5
                cla(app.UIAxes)
                 % Definimos los parámetros de la señal
                A = 1; % amplitud
                f = 20; % frecuencia
                t = 0:0.001:1; % vector de tiempo
                
                % Calculamos la señal
                s = A * f^2 * exp(-pi*f*t) .* sin(2*pi*f*t);
                
                % Graficamos la señal
                plot(app.UIAxes,t,s * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Magnitud';
                app.UIAxes.Title.String = 'Señal de movimiento sísmico';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-200,1000];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t*2,s * nPoints);

            end

            if index == 6
                cla(app.UIAxes)
                % Definir parámetros de la señal
                frecuencia = 440; % Hz
                amplitud = 1; % amplitud máxima
                duracion = 1; % duración en segundos
                fs = 44100; % frecuencia de muestreo en Hz
                
                % Calcular el número de muestras necesarias
                n_muestras = duracion * fs;
                
                % Generar vector de tiempo
                t = linspace(0, duracion, n_muestras);
                
                % Generar señal de sonido
                y = amplitud * sin(2*pi*frecuencia*t);
                
                % Graficar la señal en un axes
                plot(app.UIAxes,t, y * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Frecuencia Hz';
                app.UIAxes.Title.String = 'Señal de un sonido';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-20,20];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t*2, y * nPoints);

            end

            if index == 7
                cla(app.UIAxes)
                A = 10;
                f = 0.5;
                phi = 0;
                B = 50;
                t = 0:0.01:10; % vector de tiempo de 0 a 10 segundos, con un intervalo de 0.01 segundos
                humedad = A*sin(2*pi*f*t + phi) + B;
                
                plot(app.UIAxes,t, humedad * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Humedad';
                app.UIAxes.Title.String = 'Señal de un Humedad';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];
                
                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t*2, humedad * nPoints);

            end

            if index == 8
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 10; % m/s
                frecuencia = 0.1; % Hz
                
                % Tiempo de muestreo
                tiempo_total = 60; % segundos
                frecuencia_muestreo = 100; % Hz
                tiempo = linspace(0, tiempo_total, tiempo_total*frecuencia_muestreo);
                
                % Generación de la señal
                velocidad_viento = amplitud * sin(2*pi*frecuencia*tiempo);
                
                % Visualización de la señal
                plot(app.UIAxes,tiempo, velocidad_viento * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Velocidad en Km';
                app.UIAxes.Title.String = 'Velocidad del viento';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,tiempo*2, velocidad_viento * nPoints);

            end

            if index == 9
                cla(app.UIAxes)
                A = 100;         % Amplitud
                f = 1000;      % Frecuencia en Hz
                phi = 0;       % Fase inicial en radianes
                tau = 0.001;   % Constante de tiempo en segundos
                t = linspace(0, 0.01, 1000);  % Vector de tiempo de 0 a 0.01 segundos
                
                y = A * cos(2*pi*f*t + phi) .* exp(-t/tau);
                
                plot(app.UIAxes, t, y *nPoints);
                app.UIAxes.YLabel.String = 'Radiacion';
                app.UIAxes.XLabel.String = 'Distancia en Km';
                app.UIAxes.Title.String = 'Señales de Radiación';
                app.UIAxes.XLim = [0,.01];
                app.UIAxes.YLim = [-500,500];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, t*2, y *nPoints);

            end

            if index == 10
                cla(app.UIAxes)
                % Definir los parámetros de la señal
                f = 1;  % frecuencia en Hz
                A = 10;  % amplitud en Pascal
                
                % Crear un vector de tiempo para la señal
                t = 0:0.001:10;  % vector de tiempo de 0 a 1 segundos en pasos de 1 ms
                
                % Generar la señal sinusoidal de presión
                p = A*sin(2*pi*f*t);

                plot(app.UIAxes,t, p * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Pascal';
                app.UIAxes.Title.String = 'Señal de presión';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-200,200];

                % Llama a hold para mantener la primera trama en la pantalla
                hold(app.UIAxes, 'on');
                plot(app.UIAxes,t*2, p * nPoints);

            end
            
        end

        % Value changed function: TiempoSlider
        function TiempoSliderValueChanged(app, event)
            index = app.SealDropDown.Value;
            nPoints = app.AmplitudSlider.Value;
            nPointsX = app.TiempoSlider.Value;
            
            if index == 1
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 1;    % Amplitud de la señal
                frecuencia = 0.1;    % Frecuencia de la señal (número de ciclos por segundo)
                periodo = 1/frecuencia;    % Periodo de la señal (segundos)
                fase = pi/2;    % Fase de la señal (en radianes)
                tiempo = 0:0.01:10 * (index);    % Vector de tiempo (segundos)
                
                % Generar señal de olas
                ola = (nPoints) * amplitud * sin(2*pi*frecuencia*tiempo + fase) + ...
                      amplitud/2 * cos(2*pi*(1/periodo)*tiempo);
                  
                % Graficar señal de olas
                plot(app.UIAxes, tiempo * nPointsX, ola)
                
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Amplitud';
                app.UIAxes.Title.String = 'Señal de olas del mar';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-10,10];
            end

            if index == 2
                cla(app.UIAxes)
                % Definir los parámetros de la señal ECG
                fs = 200;           % Frecuencia de muestreo en Hz
                dur = 5;            % Duración de la señal en segundos
                amp = 1;            % Amplitud de la señal
                
                % Generar la señal ECG utilizando una ecuación matemática
                t = linspace(0, dur, dur*fs) * index;
                ecg_signal = (nPoints) * amp * (sin(2*pi*1*t) + sin(2*pi*1.25*t) + 0.5*sin(2*pi*1.5*t));
                
                % Graficar la señal ECG en un axes
                plot(app.UIAxes, t * nPointsX, ecg_signal);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Latidos';
                app.UIAxes.Title.String = 'Frecuencia cardíaca medida por un electrocardiograma (ECG)';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-20,20];
            end

            if index == 3
                cla(app.UIAxes)
                t = 0:23; % vector de tiempo de 0 a 23 horas
                ruido_fondo = 50*ones(size(t)); % señal constante de 50 dB
                ruido_variable = (nPoints)*10*sin((pi/6)*t - pi/2) + 5; % función sinusoidal con amplitud máxima de 10 dB durante las horas pico
                ruido_total = ruido_fondo + ruido_variable; % señal final de niveles de ruido ambiental

                plot(app.UIAxes, t * nPointsX, ruido_total);
                app.UIAxes.XLabel.String = 'Hora del día';
                app.UIAxes.YLabel.String = 'Nivel de ruido (dB)';
                app.UIAxes.Title.String = 'Niveles de ruido ambiental en una ciudad durante el día';
                app.UIAxes.XLim = [0,23];
                app.UIAxes.YLim = [-200,200];
            end

            if index == 4
                cla(app.UIAxes)
                fs = 1000;             % Frecuencia de muestreo
                t = 0:10/fs:10;          % Vector de tiempo
                f1 = 8;                % Frecuencia de onda alfa (en Hz)
                f2 = 15;               % Frecuencia de onda beta (en Hz)
                f3 = 30;               % Frecuencia de onda gamma (en Hz)
                amp1 = 50;             % Amplitud de la onda alfa
                amp2 = 20;             % Amplitud de la onda beta
                amp3 = 10;             % Amplitud de la onda gamma
                phase1 = pi/2;         % Fase de la onda alfa
                phase2 = pi;           % Fase de la onda beta
                phase3 = pi/4;         % Fase de la onda gamma
                signal = amp1*sin(2*pi*f1*t + phase1) + amp2*sin(2*pi*f2*t + phase2) + amp3*sin(2*pi*f3*t + phase3);
               
                plot(app.UIAxes,t * nPointsX, signal * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Actividad';
                app.UIAxes.Title.String = 'Señal de actividad cerebral medida por un electroencefalograma';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-300,300];
            end

            if index == 5
                cla(app.UIAxes)
                 % Definimos los parámetros de la señal
                A = 1; % amplitud
                f = 20; % frecuencia
                t = 0:0.001:1; % vector de tiempo
                
                % Calculamos la señal
                s = A * f^2 * exp(-pi*f*t) .* sin(2*pi*f*t);
                
                % Graficamos la señal
                plot(app.UIAxes,t * nPointsX,s * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Magnitud';
                app.UIAxes.Title.String = 'Señal de movimiento sísmico';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-200,1000];
            end

            if index == 6
                cla(app.UIAxes)
                % Definir parámetros de la señal
                frecuencia = 440; % Hz
                amplitud = 1; % amplitud máxima
                duracion = 1; % duración en segundos
                fs = 44100; % frecuencia de muestreo en Hz
                
                % Calcular el número de muestras necesarias
                n_muestras = duracion * fs;
                
                % Generar vector de tiempo
                t = linspace(0, duracion, n_muestras);
                
                % Generar señal de sonido
                y = amplitud * sin(2*pi*frecuencia*t);
                
                % Graficar la señal en un axes
                plot(app.UIAxes,t * nPointsX, y * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Frecuencia Hz';
                app.UIAxes.Title.String = 'Señal de un sonido';
                app.UIAxes.XLim = [0,.1];
                app.UIAxes.YLim = [-20,20];
            end

            if index == 7
                cla(app.UIAxes)
                A = 10;
                f = 0.5;
                phi = 0;
                B = 50;
                t = 0:0.01:10; % vector de tiempo de 0 a 10 segundos, con un intervalo de 0.01 segundos
                humedad = A*sin(2*pi*f*t + phi) + B;
                
                plot(app.UIAxes,t * nPointsX, humedad * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Humedad';
                app.UIAxes.Title.String = 'Señal de un Humedad';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];
                
            end

            if index == 8
                cla(app.UIAxes)
                % Parámetros de la señal
                amplitud = 10; % m/s
                frecuencia = 0.1; % Hz
                
                % Tiempo de muestreo
                tiempo_total = 60; % segundos
                frecuencia_muestreo = 100; % Hz
                tiempo = linspace(0, tiempo_total, tiempo_total*frecuencia_muestreo);
                
                % Generación de la señal
                velocidad_viento = amplitud * sin(2*pi*frecuencia*tiempo);
                
                % Visualización de la señal
                plot(app.UIAxes,tiempo * nPointsX, velocidad_viento * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Velocidad en Km';
                app.UIAxes.Title.String = 'Velocidad del viento';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [0,200];
            end

            if index == 9
                cla(app.UIAxes)
                A = 100;         % Amplitud
                f = 1000;      % Frecuencia en Hz
                phi = 0;       % Fase inicial en radianes
                tau = 0.001;   % Constante de tiempo en segundos
                t = linspace(0, 0.01, 1000);  % Vector de tiempo de 0 a 0.01 segundos
                
                y = A * cos(2*pi*f*t + phi) .* exp(-t/tau);
                
                plot(app.UIAxes, t * nPointsX, y *nPoints);
                app.UIAxes.YLabel.String = 'Radiacion';
                app.UIAxes.XLabel.String = 'Distancia en Km';
                app.UIAxes.Title.String = 'Señales de Radiación';
                app.UIAxes.XLim = [0,.01];
                app.UIAxes.YLim = [-500,500];
            end

            if index == 10
                cla(app.UIAxes)
                % Definir los parámetros de la señal
                f = 1;  % frecuencia en Hz
                A = 10;  % amplitud en Pascal
                
                % Crear un vector de tiempo para la señal
                t = 0:0.001:10;  % vector de tiempo de 0 a 1 segundos en pasos de 1 ms
                
                % Generar la señal sinusoidal de presión
                p = A*sin(2*pi*f*t);

                plot(app.UIAxes,t * nPointsX, p * nPoints);
                app.UIAxes.XLabel.String = 'Tiempo (segundos)';
                app.UIAxes.YLabel.String = 'Pascal';
                app.UIAxes.Title.String = 'Señal de presión';
                app.UIAxes.XLim = [0,10];
                app.UIAxes.YLim = [-200,200];
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [-10 10];
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal';
            app.SealDropDown = uidropdown(app.UIFigure);
            app.SealDropDown.Items = {'Selecciona', '1.- Onda de Olas de Mar', '2.- Frecuencia cardíaca', '3.- Ruido ambiental', '4.- Actividad cerebral', '5.- Movimiento sísmico', '6.- Sonido', '7.- Humedad', '8.- Velocidad Viento', '9.- Radiación', '10.- Presion'};
            app.SealDropDown.ItemsData = [0 1 2 3 4 5 6 7 8 9 10];
            app.SealDropDown.ValueChangedFcn = createCallbackFcn(app, @SealDropDownValueChanged, true);
            app.SealDropDown.Value = 0;
            app.AmplitudSliderLabel = uilabel(app.UIFigure);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.AmplitudSlider = uislider(app.UIFigure);
            app.AmplitudSlider.Limits = [0 10];
            app.AmplitudSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudSliderValueChanged, true);
            app.AmplitudSlider.ValueChangingFcn = createCallbackFcn(app, @AmplitudSliderValueChanging, true);
            app.AmplitudSlider.Value = 1;
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'Azul', 'Rojo', 'Verde', 'Amarillo', 'Negro'};
            app.ColorDropDown.ItemsData = [0 1 2 3 4];
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 0;
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.Amplitud2graficasSliderLabel = uilabel(app.UIFigure);
            app.Amplitud2graficasSliderLabel.Enable = 'off';
            app.Amplitud2graficasSliderLabel.Text = 'Amplitud 2 graficas';
            app.Amplitud2graficasSlider = uislider(app.UIFigure);
            app.Amplitud2graficasSlider.Limits = [0 10];
            app.Amplitud2graficasSlider.ValueChangedFcn = createCallbackFcn(app, @Amplitud2graficasSliderValueChanged, true);
            app.Amplitud2graficasSlider.Enable = 'off';
            app.Amplitud2graficasSlider.Value = 1;
            app.TiempoSliderLabel = uilabel(app.UIFigure);
            app.TiempoSliderLabel.Text = 'Tiempo';
            app.TiempoSlider = uislider(app.UIFigure);
            app.TiempoSlider.Limits = [0 10];
            app.TiempoSlider.ValueChangedFcn = createCallbackFcn(app, @TiempoSliderValueChanged, true);
            app.TiempoSlider.Value = 1;
            app.CaractersticasLabel = uilabel(app.UIFigure);
            app.CaractersticasLabel.Text = 'Características';
            app.NVNumerodeVariablesLabel = uilabel(app.UIFigure);
            app.NVNumerodeVariablesLabel.Text = 'NV (Numero de Variables)';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.DDimensionalidadLabel = uilabel(app.UIFigure);
            app.DDimensionalidadLabel.Text = 'D (Dimensionalidad)';
            app.VIVariablesIndependientesLabel = uilabel(app.UIFigure);
            app.VIVariablesIndependientesLabel.Text = 'VI (Variables Independientes)';
            app.VSValoresdelasealLabel = uilabel(app.UIFigure);
            app.VSValoresdelasealLabel.Text = 'VS (Valores de la señal)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '-';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '-';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '-';
            app.NENaturalezaestadsticaLabel = uilabel(app.UIFigure);
            app.NENaturalezaestadsticaLabel.Text = 'NE (Naturaleza estadística)';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '-';
            app.JustificacinTextAreaLabel = uilabel(app.UIFigure);
            app.JustificacinTextAreaLabel.Text = 'Justificación';
            app.JustificacinTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end