classdef P4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PotenciaLabel            matlab.ui.control.Label
        EnergaLabel              matlab.ui.control.Label
        Label                    matlab.ui.control.Label
        TipodeSealLabel          matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        txtTMax                  matlab.ui.control.EditField
        TiempoMxLabel            matlab.ui.control.Label
        txtTMin                  matlab.ui.control.EditField
        TiempoMinEditFieldLabel  matlab.ui.control.Label
        groupRep_2               matlab.ui.container.ButtonGroup
        radioAper                matlab.ui.control.RadioButton
        radioPer                 matlab.ui.control.RadioButton
        ComponenteImparLabel     matlab.ui.control.Label
        ComponenteParLabel       matlab.ui.control.Label
        SealOriginalLabel        matlab.ui.control.Label
        ParteImparEditField      matlab.ui.control.EditField
        ParteParEditField        matlab.ui.control.EditField
        SealOriginalEditField    matlab.ui.control.EditField
        sliderFrec               matlab.ui.control.Slider
        FrecuenciaLabel          matlab.ui.control.Label
        sliderAmplitud           matlab.ui.control.Slider
        AmplitudSliderLabel      matlab.ui.control.Label
        downSignal               matlab.ui.control.DropDown
        SealDropDownLabel        matlab.ui.control.Label
        txtTitulo                matlab.ui.control.Label
        btnGraficar              matlab.ui.control.Button
        groupRep                 matlab.ui.container.ButtonGroup
        radioDisc                matlab.ui.control.RadioButton
        radioCont                matlab.ui.control.RadioButton
        axes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnGraficar
        function btnGraficarPushed(app, event)
            signal = app.downSignal.Value;
            amplitud = app.sliderAmplitud.Value;
            frecuencia = app.sliderFrec.Value;
            t1 = get(app.txtTMin, 'Value');
            tmin = str2double(t1);

            t2 = get(app.txtTMax, 'Value');
            tmax = str2double(t2);

            if signal == 2 %% Senoidal
                if app.radioCont.Value == 1 %% Continuo
                    if app.radioPer.Value == 1 %Periodica
                        % Vector de tiempo
                        t = linspace(tmin, tmax, 1000); % 1000 puntos de tiempo
                        x = amplitud * sin(2 * pi * frecuencia * t);
                        
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;

                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Potencia";
                    else                        %Aperiodica
                        % Vector de tiempo
                        %duracion = 1/frecuencia;
                        f = 1;
                        t = linspace(0, f, 1000); % 1000 puntos de tiempo
                        x = amplitud * sin(2 * pi * f * t);
                        
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Potencia";

                    end

                    plot(app.axes,t, x,'b','LineWidth', 2); %Señal original
                    hold(app.axes,'on');
                    plot(app.axes,t, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    plot(app.axes,t, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Senoidal Continuo'; 

                elseif app.radioDisc.Value == 1  %% Discreto
                    if app.radioPer.Value == 1  %Periodica
                        A = amplitud;   % amplitud
                        f = frecuencia; % frecuencia en Hz
                        phi = 0; % fase en radianes
                        
                        n = tmin:tmax/1000:1; % vector de tiempo discreto
                        
                        x = A*sin(2*pi*f*n + phi); % señal senoidal
                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Ninguna";
                    else                            %Aperiódica
                        A = amplitud;   % amplitud
                        f = 1; % frecuencia en Hz
                        phi = 0; % fase en radianes
                        
                        n = tmin:tmax/1000:1; % vector de tiempo discreto
                        
                        x = A*sin(2*pi*f*n + phi); % señal senoidal
                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Ninguna";
    
                    end

                    stem(app.axes,n,x,'b','LineWidth', 2); %Grafica
                    hold(app.axes,'on');
                    stem(app.axes,n, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    stem(app.axes,n, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Senoidal Discreto'; 

                end

            elseif signal == 3 %% Exponencial
               if app.radioCont.Value == 1  %% Continuo
                    if app.radioPer.Value == 1 %Periodica
                        Fs = 10000; %Frecuencia de Muestreo
                        t = tmin:1/Fs:tmax*2; %Tiempo de Simulación
                        T1 = 1; % Período deseado en segundos
                        x = amplitud .^ (mod(t, T1)/T1); % Señal exponencial periódica
                        
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;

                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Energía";
                        
                    else           %Aperiodica
                        Fs = 10000; %Frecuencia de Muestreo
                        t = tmin:1/Fs:tmax*2; %Tiempo de Simulación
                        x = amplitud.^ t;%exp(t); %Generación de Señal
                        
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;

                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Energía";
                    end

                    plot(app.axes,t, x,'b','LineWidth', 2); %Señal original
                    hold(app.axes,'on');
                    plot(app.axes,t, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    plot(app.axes,t, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Exponencial Continuo'; 

               elseif app.radioDisc.Value == 1 %% Discreto
                    if app.radioPer.Value == 1 %Periodica
                        n = tmin:tmax*2; %Tiempo de Simulación
                        T1 = 10;
                        x = amplitud .^ (mod(n, T1)/T1); % Señal periódica
                        
                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Ninguna";
                    else %Aperiodica
                        n = tmin:tmax*2; %Tiempo de Simulación
                        x = amplitud.^ n; %Generación de Señal
                        
                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Ninguna";
                    end

                    stem(app.axes,n,x,'b','LineWidth', 2); %Grafica
                    hold(app.axes,'on');
                    stem(app.axes,n, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    stem(app.axes,n, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Exponencial Discreto'; 
               end
            
            elseif signal == 4 %% Rampa
                if app.radioCont.Value == 1 %% Continuo
                    if app.radioPer.Value == 1 %Periodica
                        Fs = 10000;          % Frecuencia de Muestreo
                        t1 = tmin:1/Fs:tmax; 
                        t2 = tmax:1/Fs:tmax*2;
                        t = [t1,t2];         % Tiempo de Simulación
                        r = linspace(0, amplitud, length(t1));
                        
                        x = amplitud.*[zeros(1,length(t1)),r]; % Generación de Señal
    
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Potencia"; 

                    else                        %Aperiodica
                        Fs = 10000;          % Frecuencia de Muestreo
                        t1 = tmin:1/Fs:tmax; 
                        t2 = tmax:1/Fs:tmax*2;
                        t = [t1,t2];         % Tiempo de Simulación
                        r = linspace(0, 1, length(t1));
                        
                        x = amplitud.*[zeros(1,length(t1)),r]; % Generación de Señal
    
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                                                
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Potencia";
                    end
                    
                    plot(app.axes,t, x,'b'); %Señal original
                    hold(app.axes,'on');
                    plot(app.axes,t, xp,'m'); %Parte par
                    hold(app.axes,'on');
                    plot(app.axes,t, xi,'g'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Rampa Continuo'; 
               elseif app.radioDisc.Value == 1                    %% Discreto
                    if app.radioPer.Value == 1 %Periodica
                        n1 = tmin:tmax/2; 
                        n2 = tmax/2:tmax;
                        n = [n1,n2];         % Tiempo de Simulación
                        r = linspace(0, amplitud, length(n1));
                        r = floor(r);
                        x = amplitud.*[zeros(1,length(n1)),r]; % Generación de Señal

                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                                                
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Ninguna";
                    else
                        n1 = tmin:tmax/2; 
                        n2 = tmax/2:tmax;
                        n = [n1,n2];         % Tiempo de Simulación
                        r = linspace(0, amplitud, length(n1));
                        r = floor(r);
                        x = amplitud.*[zeros(1,length(n1)),r]; % Generación de Señal

                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                                                
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Ninguna";
                    end
                        stem(app.axes,n,x,'b','LineWidth', 2); %Grafica
                        hold(app.axes,'on');
                        stem(app.axes,n, xp,'m--'); %Parte par
                        hold(app.axes,'on');
                        stem(app.axes,n, xi,'g--'); %Parte impar
                        hold(app.axes,"off");
                        app.axes.Title.String ='Rampa Discreto'; 
                end

            elseif signal == 5 %% Cuadrada
                if app.radioCont.Value == 1  %% Continuo    
                    if app.radioPer.Value == 1 %Periodica
                        duty = 50; %Ciclo útil
                        t = linspace(tmin, tmax, 1000);
                       
                        x = amplitud*square(2*pi*frecuencia*t,duty); %Generación de Señal
                        %plot(app.axes,t,x);
                        
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Potencia";
                    else                        %Aperiodica
                        duty = 50; %Ciclo útil
                        t = linspace(0, 1, 1000);
                       
                        x = amplitud*square(2*pi*1*t,duty); %Generación de Señal
                        %plot(app.axes,t,x);
                        
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Energía";
                    end

                    plot(app.axes,t, x,'b','LineWidth', 2); %Señal original
                    hold(app.axes,'on');
                    plot(app.axes,t, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    plot(app.axes,t, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Cuadrada Continuo'; 
               elseif app.radioDisc.Value == 1                    %% Discreto
                    if app.radioPer.Value == 1 %Periodica
                        f = frecuencia/10; %Frecuencia Fundamental
                        T = tmax*(1/f); %Número de Periodos a Graficar
    
                        duty = 50; %Ciclo útil
                        n = tmin:T; %Tiempo de Simulación
    
                        x = amplitud*square(2*pi*f*n,duty); %Generación de Señal
                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                                                
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Potencia";
                        
                    else                        %Aperiodica
                        f = 1; %Frecuencia Fundamental
                        %T = tmax*(1/f); %Número de Periodos a Graficar
    
                        duty = 50; %Ciclo útil
                        n = tmin:tmax/1000:1; %Tiempo de Simulación
    
                        x = amplitud*square(2*pi*f*n,duty); %Generación de Señal
                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                                                
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Energia";
                    end

                    stem(app.axes,n,x,'b','LineWidth', 2); %Grafica
                    hold(app.axes,'on');
                    stem(app.axes,n, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    stem(app.axes,n, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Cuadrada Discreto';
               end
            elseif signal == 6 %% Triangular
                if app.radioCont.Value == 1  %% Continuo
                    if app.radioPer.Value == 1 %Periodica
                        t = linspace(tmin, tmax, 1000);
                        x = amplitud*sawtooth(2*pi*t,0.5); %Generación de Señal
                        
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Potencia";
                    else                        %Aperiodica
                        t = linspace(0, 1, 1000);
                        x = amplitud*sawtooth(2*pi*t,0.5); %Generación de Señal
                        
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo

                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Energia";
                    end

                    plot(app.axes,t, x,'b','LineWidth', 2); %Señal original
                    hold(app.axes,'on');
                    plot(app.axes,t, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    plot(app.axes,t, xi,'g--'); %Parte impar
                    hold(app.axes,"off");

                    app.axes.Title.String ='Triangular Continuo'; 
               elseif app.radioDisc.Value == 1 %% Discreto
                    if app.radioPer.Value == 1 %Periodica
                        n = tmin:0.1:tmax; %Tiempo de Simulación
                        x = amplitud*sawtooth(2*pi*n,0.5); %Generación de Señal
                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                                                
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Potencia";
                    else                        %Aperiodica
                        n = tmin:tmax/1000:1; %Tiempo de Simulación
                        x = amplitud*sawtooth(2*pi*n,0.5); %Generación de Señal
                        xp = (x + flip(x))/2;
                        xi = (x - flip(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                                                
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal de Energia";
                    end
                    stem(app.axes,n,x,'b','LineWidth', 2); %Grafica
                    hold(app.axes,'on');
                    stem(app.axes,n, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    stem(app.axes,n, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Triangular Discreto';

               end
            elseif signal == 7 %% Escalón Unitario
                if app.radioCont.Value == 1 %% Continuo
                    if app.radioPer.Value == 1 %Periodica
                        t = linspace(tmin, tmax, 1000);
                        x = amplitud * heaviside(t); % Generación de la señal
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal Energia";
                    else                        %Aperiodica
                        t = linspace(0, 1, 1000);
                        x = amplitud * heaviside(t); % Generación de la señal
                        xp = (x + fliplr(x))/2;
                        xi = (x - fliplr(x))/2;
                        T = (tmax - tmin) / 1000; % intervalo de muestreo
                        
                        E = sum(abs(x).^2) * T;
                        P = mean(abs(x).^2) / T;
                        
                        app.EnergaLabel.Text = "Energía = "+E;
                        app.PotenciaLabel.Text = "Potencia = "+P;
                        app.Label.Text = "Señal Energia";
                    end

                    plot(app.axes,t, x,'b','LineWidth', 2); %Señal original
                    hold(app.axes,'on');
                    plot(app.axes,t, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    plot(app.axes,t, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Escalón Unitario Continuo'; 
               elseif app.radioDisc.Value == 1  %% Discreto
                    if app.radioPer.Value == 1 %Periodica
                       n = tmin:tmax; %Tiempo de Simulación
    
                       x = amplitud*heaviside(n); %Generación de Señal
                       xp = (x + flip(x))/2;
                       xi = (x - flip(x))/2;
                       T = (tmax - tmin) / 1000; % intervalo de muestreo
                       
                       E = sum(abs(x).^2) * T;
                       P = mean(abs(x).^2) / T;
                                                
                       app.EnergaLabel.Text = "Energía = "+E;
                       app.PotenciaLabel.Text = "Potencia = "+P;
                       app.Label.Text = "Ninguna";
                    else                        %Aperiodica
                       n = 0:1; %Tiempo de Simulación
    
                       x = amplitud*heaviside(n); %Generación de Señal
                       xp = (x + flip(x))/2;
                       xi = (x - flip(x))/2;
                       T = (tmax - tmin) / 1000; % intervalo de muestreo
                       
                       E = sum(abs(x).^2) * T;
                       P = mean(abs(x).^2) / T;
                                                
                       app.EnergaLabel.Text = "Energía = "+E;
                       app.PotenciaLabel.Text = "Potencia = "+P;
                       app.Label.Text = "Ninguna";
                    end

                    stem(app.axes,n,x,'b','LineWidth', 2); %Grafica
                    hold(app.axes,'on');
                    stem(app.axes,n, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    stem(app.axes,n, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
                    app.axes.Title.String ='Escalón Unitario Discreto';
               end
            elseif signal == 8 %% Impulso unitario
                if app.radioPer.Value == 1 %Periodica
                   n = 0:5; %Tiempo de Simulación
                   x = amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal

                   xp = (x + fliplr(x))/2;
                   xi = (x - fliplr(x))/2;
                   T = (tmax - tmin) / 1000; % intervalo de muestreo
                    
                   E = sum(abs(x).^2) * T;
                   P = mean(abs(x).^2) / T;
                    
                   app.EnergaLabel.Text = "Energía = "+E;
                   app.PotenciaLabel.Text = "Potencia = "+P;
                   app.Label.Text = "Ninguna";
                   app.axes.Title.String ='Impulso Unitario'; 
                else                        %Aperiodica
                   n = 0:5; %Tiempo de Simulación
                   x = amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)]; %Generación de Señal

                   xp = (x + fliplr(x))/2;
                   xi = (x - fliplr(x))/2;
                   T = (tmax - tmin) / 1000; % intervalo de muestreo
                    
                   E = sum(abs(x).^2) * T;
                   P = mean(abs(x).^2) / T;
                    
                   app.EnergaLabel.Text = "Energía = "+E;
                   app.PotenciaLabel.Text = "Potencia = "+P;
                   app.Label.Text = "Ninguna";
                   app.axes.Title.String ='Impulso Unitario'; 
                end

                    stem(app.axes,n,x,'b','LineWidth', 2); %Grafica
                    hold(app.axes,'on');
                    stem(app.axes,n, xp,'m--'); %Parte par
                    hold(app.axes,'on');
                    stem(app.axes,n, xi,'g--'); %Parte impar
                    hold(app.axes,"off");
            end
        end

        % Value changed function: downSignal
        function downSignalValueChanged(app, event)
            signal = app.downSignal.Value;
            if signal == 3
                set(app.sliderFrec, 'Enable', 'off');
            elseif signal == 4
                set(app.sliderFrec, 'Enable', 'off');
            elseif signal == 7
                set(app.sliderFrec, 'Enable', 'off');
            elseif signal == 8
                set(app.sliderFrec, 'Enable', 'off');
            else
                set(app.sliderFrec, 'Enable', 'on');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axes = uiaxes(app.UIFigure);
            title(app.axes, 'Gráfica')
            xlabel(app.axes, 'Tiempo (s)')
            ylabel(app.axes, 'Amplitud')
            zlabel(app.axes, 'Z')
            app.axes.YLim = [-10 10];
            app.axes.YTick = [-10 -5 0 5 10];
            app.axes.YTickLabel = {'-10'; '-5'; '0'; '5'; '10'};
            app.groupRep = uibuttongroup(app.UIFigure);
            app.groupRep.Title = 'Representación';
            app.radioCont = uiradiobutton(app.groupRep);
            app.radioCont.Text = 'Continua';
            app.radioCont.Value = true;
            app.radioDisc = uiradiobutton(app.groupRep);
            app.radioDisc.Text = 'Discreta';
            app.btnGraficar = uibutton(app.UIFigure, 'push');
            app.btnGraficar.ButtonPushedFcn = createCallbackFcn(app, @btnGraficarPushed, true);
            app.btnGraficar.Text = 'Graficar';
            app.txtTitulo = uilabel(app.UIFigure);
            app.txtTitulo.Text = 'Generador de Señales en MatLab';
            app.SealDropDownLabel = uilabel(app.UIFigure);
            app.SealDropDownLabel.Text = 'Señal:';
            app.downSignal = uidropdown(app.UIFigure);
            app.downSignal.Items = {'Seleccionar', 'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalón Unitario', 'Impulso Unitario'};
            app.downSignal.ItemsData = [1 2 3 4 5 6 7 8];
            app.downSignal.ValueChangedFcn = createCallbackFcn(app, @downSignalValueChanged, true);
            app.downSignal.Value = 1;
            app.AmplitudSliderLabel = uilabel(app.UIFigure);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.sliderAmplitud = uislider(app.UIFigure);
            app.sliderAmplitud.Limits = [0 10];
            app.sliderAmplitud.Value = 1;
            app.FrecuenciaLabel = uilabel(app.UIFigure);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.sliderFrec = uislider(app.UIFigure);
            app.sliderFrec.Limits = [0 10];
            app.sliderFrec.Value = 1;
            app.SealOriginalEditField = uieditfield(app.UIFigure, 'text');
            app.SealOriginalEditField.Editable = 'off';
            app.ParteParEditField = uieditfield(app.UIFigure, 'text');
            app.ParteParEditField.Editable = 'off';
            app.ParteImparEditField = uieditfield(app.UIFigure, 'text');
            app.ParteImparEditField.Editable = 'off';
            app.SealOriginalLabel = uilabel(app.UIFigure);
            app.SealOriginalLabel.Text = 'Señal Original';
            app.ComponenteParLabel = uilabel(app.UIFigure);
            app.ComponenteParLabel.Text = 'Componente Par';
            app.ComponenteImparLabel = uilabel(app.UIFigure);
            app.ComponenteImparLabel.Text = 'Componente Impar';
            app.groupRep_2 = uibuttongroup(app.UIFigure);
            app.groupRep_2.Title = 'Tipo';
            app.radioPer = uiradiobutton(app.groupRep_2);
            app.radioPer.Text = 'Periódica';
            app.radioPer.Value = true;
            app.radioAper = uiradiobutton(app.groupRep_2);
            app.radioAper.Text = 'Aperiódica';
            app.TiempoMinEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoMinEditFieldLabel.Text = 'Tiempo Min:';
            app.txtTMin = uieditfield(app.UIFigure, 'text');
            app.txtTMin.Value = '0';
            app.TiempoMxLabel = uilabel(app.UIFigure);
            app.TiempoMxLabel.Text = 'Tiempo Máx:';
            app.txtTMax = uieditfield(app.UIFigure, 'text');
            app.txtTMax.Value = '5';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Si E(∞) < ∞   y   P(∞) = 0  => Señal de Energía'; ''; 'Si E(∞) = ∞   y   P(∞) < ∞ => Señal de Potencia'};
            app.TipodeSealLabel = uilabel(app.UIFigure);
            app.TipodeSealLabel.Text = 'Tipo de Señal:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '-';
            app.EnergaLabel = uilabel(app.UIFigure);
            app.EnergaLabel.Text = 'Energía: ';
            app.PotenciaLabel = uilabel(app.UIFigure);
            app.PotenciaLabel.Text = 'Potencia: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end