classdef P5_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        window1                      matlab.ui.Figure
        btnWindow                    matlab.ui.control.Button
        cboxReflex                   matlab.ui.control.CheckBox
        txtDespla                    matlab.ui.control.EditField
        EscalamientodeTiempoLabel_3  matlab.ui.control.Label
        txtEscala                    matlab.ui.control.EditField
        EscalamientodeTiempoLabel_2  matlab.ui.control.Label
        ReflexinLabel                matlab.ui.control.Label
        txtTMax                      matlab.ui.control.EditField
        TiempoMxLabel                matlab.ui.control.Label
        txtTMin                      matlab.ui.control.EditField
        TiempoMinEditFieldLabel      matlab.ui.control.Label
        sliderFrec                   matlab.ui.control.Slider
        FrecuenciaLabel              matlab.ui.control.Label
        sliderAmplitud               matlab.ui.control.Slider
        AmplitudSliderLabel          matlab.ui.control.Label
        downSignal                   matlab.ui.control.DropDown
        SealDropDownLabel            matlab.ui.control.Label
        txtTitulo                    matlab.ui.control.Label
        btnGraficar                  matlab.ui.control.Button
        groupRep                     matlab.ui.container.ButtonGroup
        radioDisc                    matlab.ui.control.RadioButton
        radioCont                    matlab.ui.control.RadioButton
        axes2                        matlab.ui.control.UIAxes
        axes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnGraficar
        function btnGraficarPushed(app, event)
            signal = app.downSignal.Value;
            amplitud = app.sliderAmplitud.Value;
            frecuencia = app.sliderFrec.Value;

            t1 = get(app.txtTMin, 'Value');
            tmin = str2double(t1);

            t2 = get(app.txtTMax, 'Value');
            tmax = str2double(t2);

            escTiem = get(app.txtEscala, 'Value');
            escalamiento = str2double(escTiem);

            reflexion = app.cboxReflex.Value;

            despTiem = get(app.txtDespla, 'Value');
            desplazamiento = str2double(despTiem);    

            switch signal
                case 2 %SENOIDAL
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
    
                    t = linspace(tmin, tmax, puntoTiempo); 
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                        
                    x = 2 * pi * frecuencia * t;
    
                    y1 = amplitud * sin(x); %Señal original
                        
                    if reflexion == 1 %Reflexión -> escalamiento = -1
                        aux = escalamiento * (x + desplazamiento);
                        y2 = amplitud * sin( aux * -1); %Señal desplazada, escalada y reflejada
                    else
                        y2 = amplitud * sin( escalamiento * (x + desplazamiento)); %Señal desplazada y escalada
                    end

                case 3 %EXPONENCIAL
                    Fs = 10; 
                    t = tmin:1/Fs:tmax;
                    t2 = tmin+desplazamiento:1/Fs:tmax+desplazamiento; 
                        
                    y1 = amplitud.^ t; 
    
                    if reflexion == 1 
	                    aux = amplitud.^( escalamiento * (t + desplazamiento));
	                    y2 = fliplr(aux);
                    else
                        y2 = amplitud.^ (escalamiento * (t + desplazamiento));    
                    end

                case 4 %RAMPA
                    if app.radioCont.Value == 1
                        puntoTiempo = 10000;
                    else
                        puntoTiempo = 10;
                    end
    
                    Fs = puntoTiempo;
                
                    ta = 0:1/Fs:tmax;
                    tb = tmax:1/Fs:tmax*2;
                    t = [ta,tb];
                    t2= [escalamiento * (ta + desplazamiento), escalamiento * (tb + desplazamiento)];
                
                    r = linspace(0, amplitud, length(ta));
                    y1 = amplitud.*[zeros(1,length(ta)),r];
                
                    if reflexion == 1
                        t2= [(escalamiento * (ta + desplazamiento))*-1, (escalamiento * (tb + desplazamiento))*-1];
                    end
                
                    y2 = amplitud.*[zeros(1,length(ta)),r];

                case 5 %CUADRADA
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
    
                    duty = 50;
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                    
                    x = 2*pi*frecuencia*t;
                    
                    y1 = amplitud*square(x,duty); 
                    
                    if reflexion == 1
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * square( aux * -1, duty); 
                    else
                        y2 = amplitud * square( escalamiento * (x + desplazamiento),duty);
                    end                      

                case 6 %TRIANGULAR
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
                    
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                                        
                    x = 2*pi*t;
                    y1 = amplitud*sawtooth(x,0.5);
                                        
                    if reflexion == 1 
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * sawtooth( aux * -1,0.5);
                    else
                        y2 = amplitud * sawtooth( escalamiento * (x + desplazamiento),0.5); 
                    end


                case 7 %ESCALON UNITARIO
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
    
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+ desplazamiento, tmax+ desplazamiento, puntoTiempo);
                        
                    y1 =  heaviside(t); 
                    %x2 =  heaviside(t2)*escalamiento;
                        
                    if reflexion == 1
                        aux = escalamiento * (heaviside(t2) );
                        y2 = aux * -1; 
                    else
                        y2 = escalamiento * (heaviside(t2) );
                    end

                case 8 %IMPULSO UNITARIO
                    t = tmin:tmax;
                    t2 = (tmin+ desplazamiento): (tmax+ desplazamiento);
    
                    y1 = amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)]; 
                    y2 = (amplitud*escalamiento).*[zeros(1,3),ones(1,1),zeros(1,2)];

            end

            if app.radioCont.Value == 1
                plot(app.axes,t, y1,'b','LineWidth', 2); 
                plot(app.axes2,t2, y2,'m','LineWidth', 2); 
            elseif app.radioDisc.Value == 1
                stem(app.axes,t, y1,'b','LineWidth', 2); 
                stem(app.axes2,t2, y2,'m','LineWidth', 2); 
            end


        end

        % Button pushed function: btnWindow
        function btnWindowButtonPushed(app, event)
            P5_2
            app.window1.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.window1 = uifigure('Visible', 'off');
            app.window1.Name = 'MATLAB App';
            app.axes = uiaxes(app.window1);
            title(app.axes, 'Gráfica')
            xlabel(app.axes, 'Tiempo (s)')
            ylabel(app.axes, 'Amplitud')
            zlabel(app.axes, 'Z')
            app.axes.YLim = [-10 10];
            app.axes.YTick = [-10 -5 0 5 10];
            app.axes.YTickLabel = {'-10'; '-5'; '0'; '5'; '10'};
            app.axes2 = uiaxes(app.window1);
            title(app.axes2, 'Gráfica')
            xlabel(app.axes2, 'Tiempo (s)')
            ylabel(app.axes2, 'Amplitud')
            zlabel(app.axes2, 'Z')
            app.axes2.YLim = [-10 10];
            app.axes2.YTick = [-10 -5 0 5 10];
            app.axes2.YTickLabel = {'-10'; '-5'; '0'; '5'; '10'};
            app.groupRep = uibuttongroup(app.window1);
            app.groupRep.Title = 'Representación';
            app.radioCont = uiradiobutton(app.groupRep);
            app.radioCont.Text = 'Continua';
            app.radioCont.Value = true;
            app.radioDisc = uiradiobutton(app.groupRep);
            app.radioDisc.Text = 'Discreta';
            app.btnGraficar = uibutton(app.window1, 'push');
            app.btnGraficar.ButtonPushedFcn = createCallbackFcn(app, @btnGraficarPushed, true);
            app.btnGraficar.Text = 'Graficar';
            app.txtTitulo = uilabel(app.window1);
            app.txtTitulo.Text = 'Operaciones sobre la variable Independiente';
            app.SealDropDownLabel = uilabel(app.window1);
            app.SealDropDownLabel.Text = 'Señal:';
            app.downSignal = uidropdown(app.window1);
            app.downSignal.Items = {'Seleccionar', 'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalón Unitario', 'Impulso Unitario'};
            app.downSignal.ItemsData = [1 2 3 4 5 6 7 8];
            app.downSignal.Value = 1;
            app.AmplitudSliderLabel = uilabel(app.window1);
            app.AmplitudSliderLabel.Text = 'Amplitud';
            app.sliderAmplitud = uislider(app.window1);
            app.sliderAmplitud.Limits = [0 10];
            app.sliderAmplitud.Value = 4;
            app.FrecuenciaLabel = uilabel(app.window1);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.sliderFrec = uislider(app.window1);
            app.sliderFrec.Limits = [0 10];
            app.sliderFrec.Value = 1;
            app.TiempoMinEditFieldLabel = uilabel(app.window1);
            app.TiempoMinEditFieldLabel.Text = 'Tiempo Min:';
            app.txtTMin = uieditfield(app.window1, 'text');
            app.txtTMin.Value = '0';
            app.TiempoMxLabel = uilabel(app.window1);
            app.TiempoMxLabel.Text = 'Tiempo Máx:';
            app.txtTMax = uieditfield(app.window1, 'text');
            app.txtTMax.Value = '5';
            app.ReflexinLabel = uilabel(app.window1);
            app.ReflexinLabel.Text = 'Reflexión';
            app.EscalamientodeTiempoLabel_2 = uilabel(app.window1);
            app.EscalamientodeTiempoLabel_2.Text = 'Escalamiento de Tiempo:';
            app.txtEscala = uieditfield(app.window1, 'text');
            app.txtEscala.Value = '1';
            app.EscalamientodeTiempoLabel_3 = uilabel(app.window1);
            app.EscalamientodeTiempoLabel_3.Text = 'Desplazamiento de Tiempo: t + ';
            app.txtDespla = uieditfield(app.window1, 'text');
            app.txtDespla.Value = '0';
            app.cboxReflex = uicheckbox(app.window1);
            app.cboxReflex.Text = '';
            app.btnWindow = uibutton(app.window1, 'push');
            app.btnWindow.ButtonPushedFcn = createCallbackFcn(app, @btnWindowButtonPushed, true);
            app.btnWindow.Text = 'Variable Dependiente';
            % Show the figure after all components are created
            app.window1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P5_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.window1)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.window1)
        end
    end
end