classdef P5_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        window2                      matlab.ui.Figure
        txtEscala_2                  matlab.ui.control.EditField
        EscalamientodeTiempoLabel_4  matlab.ui.control.Label
        txtDespla_2                  matlab.ui.control.EditField
        EscalamientodeTiempoLabel_3  matlab.ui.control.Label
        cboxReflex_2                 matlab.ui.control.CheckBox
        ReflexinLabel_2              matlab.ui.control.Label
        txtDespla                    matlab.ui.control.EditField
        EscalamientodeTiempoLabel_2  matlab.ui.control.Label
        txtEscala                    matlab.ui.control.EditField
        EscalamientodeTiempoLabel    matlab.ui.control.Label
        cboxReflex                   matlab.ui.control.CheckBox
        ReflexinLabel                matlab.ui.control.Label
        txtTitulo                    matlab.ui.control.Label
        btnWindow                    matlab.ui.control.Button
        FrecuenciaLabel_2            matlab.ui.control.Label
        IntegrarButton_2             matlab.ui.control.Button
        DerivarButton_2              matlab.ui.control.Button
        IntegrarButton               matlab.ui.control.Button
        DerivarButton                matlab.ui.control.Button
        EscalamientoAmplitudLabel_2  matlab.ui.control.Label
        MultiplicarButton            matlab.ui.control.Button
        SumarButton                  matlab.ui.control.Button
        txtTMax_2                    matlab.ui.control.EditField
        txtTMin_2                    matlab.ui.control.EditField
        sliderFrec_2                 matlab.ui.control.Slider
        sliderAmplitud_2             matlab.ui.control.Slider
        downSignal_2                 matlab.ui.control.DropDown
        btnGraficar_2                matlab.ui.control.Button
        groupRep_2                   matlab.ui.container.ButtonGroup
        radioDisc_2                  matlab.ui.control.RadioButton
        radioCont_2                  matlab.ui.control.RadioButton
        txtTMax                      matlab.ui.control.EditField
        TiempoMxLabel                matlab.ui.control.Label
        txtTMin                      matlab.ui.control.EditField
        TiempoMinEditFieldLabel      matlab.ui.control.Label
        sliderFrec                   matlab.ui.control.Slider
        FrecuenciaLabel              matlab.ui.control.Label
        sliderAmplitud               matlab.ui.control.Slider
        EscalamientoAmplitudLabel    matlab.ui.control.Label
        downSignal                   matlab.ui.control.DropDown
        SealDropDownLabel            matlab.ui.control.Label
        btnGraficar                  matlab.ui.control.Button
        groupRep                     matlab.ui.container.ButtonGroup
        radioDisc                    matlab.ui.control.RadioButton
        radioCont                    matlab.ui.control.RadioButton
        axes_2                       matlab.ui.control.UIAxes
        axes_3                       matlab.ui.control.UIAxes
        axes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnGraficar
        function btnGraficarPushed(app, event)
            signal = app.downSignal_2.Value;
            amplitud = app.sliderAmplitud_2.Value;
            frecuencia = app.sliderFrec_2.Value;

            tmin = str2double(get(app.txtTMin_2, 'Value'));
            tmax = str2double(get(app.txtTMax_2, 'Value'));

            escalamiento = str2double(get(app.txtEscala_2, 'Value'));
            reflexion = app.cboxReflex_2.Value;
            desplazamiento = str2double(get(app.txtDespla_2, 'Value'));   

            switch signal
                case 2 %SENOIDAL
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
    
                    t = linspace(tmin, tmax, puntoTiempo); 
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                        
                    x = 2 * pi * frecuencia * t;
    
                    y1 = amplitud * sin(x); %Señal original
                        
                    if reflexion == 1 %Reflexión -> escalamiento = -1
                        aux = escalamiento * (x + desplazamiento);
                        y2 = amplitud * sin( aux * -1); %Señal desplazada, escalada y reflejada
                    else
                        y2 = amplitud * sin( escalamiento * (x + desplazamiento)); %Señal desplazada y escalada
                    end

                    if app.radioCont.Value == 1
                        plot(app.axes,t2, y2,'m','LineWidth', 2); 
                    elseif app.radioDisc.Value == 1
                        stem(app.axes,t2, y2,'m','LineWidth', 2); 
                    end

                case 3 %EXPONENCIAL
                    Fs = 10; 
                    t = tmin:1/Fs:tmax;
                    t2 = tmin+desplazamiento:1/Fs:tmax+desplazamiento; 
                        
                    y1 = amplitud.^ t; 
    
                    if reflexion == 1 
	                    aux = amplitud.^( escalamiento * (t + desplazamiento));
	                    y2 = fliplr(aux);
                    else
                        y2 = amplitud.^ (escalamiento * (t + desplazamiento));    
                    end

                case 4 %RAMPA
                    if app.radioCont.Value == 1
                        puntoTiempo = 10000;
                    else
                        puntoTiempo = 10;
                    end
    
                    Fs = puntoTiempo;
                
                    ta = 0:1/Fs:tmax;
                    tb = tmax:1/Fs:tmax*2;
                    t = [ta,tb];
                    t2= [escalamiento * (ta + desplazamiento), escalamiento * (tb + desplazamiento)];
                
                    r = linspace(0, amplitud, length(ta));
                    y1 = amplitud.*[zeros(1,length(ta)),r];
                
                    if reflexion == 1
                        t2= [(escalamiento * (ta + desplazamiento))*-1, (escalamiento * (tb + desplazamiento))*-1];
                    end
                
                    y2 = amplitud.*[zeros(1,length(ta)),r];

                case 5 %CUADRADA
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
    
                    duty = 50;
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                    
                    x = 2*pi*frecuencia*t;
                    
                    y1 = amplitud*square(x,duty); 
                    
                    if reflexion == 1
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * square( aux * -1, duty); 
                    else
                        y2 = amplitud * square( escalamiento * (x + desplazamiento),duty);
                    end                      

                case 6 %TRIANGULAR
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
                    
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                                        
                    x = 2*pi*t;
                    y1 = amplitud*sawtooth(x,0.5);
                                        
                    if reflexion == 1 
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * sawtooth( aux * -1,0.5);
                    else
                        y2 = amplitud * sawtooth( escalamiento * (x + desplazamiento),0.5); 
                    end

                case 7 %ESCALON UNITARIO
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
    
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+ desplazamiento, tmax+ desplazamiento, puntoTiempo);
                        
                    y1 =  heaviside(t); 
                    %x2 =  heaviside(t2)*escalamiento;
                        
                    if reflexion == 1
                        aux = escalamiento * (heaviside(t2) );
                        y2 = aux * -1; 
                    else
                        y2 = escalamiento * (heaviside(t2) );
                    end

                case 8 %IMPULSO UNITARIO
                    t = tmin:tmax;
                    t2 = (tmin+ desplazamiento): (tmax+ desplazamiento);
    
                    y1 = amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)]; 
                    y2 = (amplitud*escalamiento).*[zeros(1,3),ones(1,1),zeros(1,2)];

            end

            
        end

        % Button pushed function: btnGraficar_2
        function btnGraficar_2ButtonPushed(app, event)
            signal = app.downSignal.Value;
            amplitud = app.sliderAmplitud.Value;
            frecuencia = app.sliderFrec.Value;

            tmin = str2double(get(app.txtTMin, 'Value'));
            tmax = str2double(get(app.txtTMax, 'Value'));

            escalamiento = str2double(get(app.txtEscala, 'Value'));
            reflexion = app.cboxReflex.Value;
            desplazamiento = str2double(get(app.txtDespla, 'Value'));   

            switch signal
                case 2 %SENOIDAL
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
    
                    t = linspace(tmin, tmax, puntoTiempo); 
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                        
                    x = 2 * pi * frecuencia * t;
    
                    y1 = amplitud * sin(x); %Señal original
                        
                    if reflexion == 1 %Reflexión -> escalamiento = -1
                        aux = escalamiento * (x + desplazamiento);
                        y2 = amplitud * sin( aux * -1); %Señal desplazada, escalada y reflejada
                    else
                        y2 = amplitud * sin( escalamiento * (x + desplazamiento)); %Señal desplazada y escalada
                    end

                case 3 %EXPONENCIAL
                    Fs = 10; 
                    t = tmin:1/Fs:tmax;
                    t2 = tmin+desplazamiento:1/Fs:tmax+desplazamiento; 
                        
                    y1 = amplitud.^ t; 
    
                    if reflexion == 1 
	                    aux = amplitud.^( escalamiento * (t + desplazamiento));
	                    y2 = fliplr(aux);
                    else
                        y2 = amplitud.^ (escalamiento * (t + desplazamiento));    
                    end

                case 4 %RAMPA
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 10000;
                    else
                        puntoTiempo = 10;
                    end
    
                    Fs = puntoTiempo;
                
                    ta = 0:1/Fs:tmax;
                    tb = tmax:1/Fs:tmax*2;
                    t = [ta,tb];
                    t2= [escalamiento * (ta + desplazamiento), escalamiento * (tb + desplazamiento)];
                
                    r = linspace(0, amplitud, length(ta));
                    y1 = amplitud.*[zeros(1,length(ta)),r];
                
                    if reflexion == 1
                        t2= [(escalamiento * (ta + desplazamiento))*-1, (escalamiento * (tb + desplazamiento))*-1];
                    end
                
                    y2 = amplitud.*[zeros(1,length(ta)),r];

                case 5 %CUADRADA
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
    
                    duty = 50;
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                    
                    x = 2*pi*frecuencia*t;
                    
                    y1 = amplitud*square(x,duty); 
                    
                    if reflexion == 1
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * square( aux * -1, duty); 
                    else
                        y2 = amplitud * square( escalamiento * (x + desplazamiento),duty);
                    end                      

                case 6 %TRIANGULAR
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
                    
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                                        
                    x = 2*pi*t;
                    y1 = amplitud*sawtooth(x,0.5);
                                        
                    if reflexion == 1 
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * sawtooth( aux * -1,0.5);
                    else
                        y2 = amplitud * sawtooth( escalamiento * (x + desplazamiento),0.5); 
                    end

                case 7 %ESCALON UNITARIO
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 100;
                    end
    
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+ desplazamiento, tmax+ desplazamiento, puntoTiempo);
                        
                    y1 =  heaviside(t); 
                    %x2 =  heaviside(t2)*escalamiento;
                        
                    if reflexion == 1
                        aux = escalamiento * (heaviside(t2) );
                        y2 = aux * -1; 
                    else
                        y2 = escalamiento * (heaviside(t2) );
                    end

                case 8 %IMPULSO UNITARIO
                    t = tmin:tmax;
                    t2 = (tmin+ desplazamiento): (tmax+ desplazamiento);
    
                    y1 = amplitud.*[zeros(1,3),ones(1,1),zeros(1,2)]; 
                    y2 = (amplitud*escalamiento).*[zeros(1,3),ones(1,1),zeros(1,2)];

            end

            if app.radioCont_2.Value == 1
                plot(app.axes_2,t2, y2,'m','LineWidth', 2); 
            elseif app.radioDisc_2.Value == 1
                stem(app.axes_2,t2, y2,'m','LineWidth', 2); 
            end
            

        end

        % Button pushed function: MultiplicarButton
        function MultiplicarButtonPushed(app, event)
            %clear
            cla(app.axes_3);
            
            % Obtener los datos de la señal graficada en "app.axes"
            linea1 = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata1 = linea1.YData; % Obtener los datos de la señal
            
            % Obtener los datos de la señal graficada en "app.axes_2"
            linea2 = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata2 = linea2.YData; % Obtener los datos de la señal
            
            % Multiplicar las señales
            if length(ydata1) > length(ydata2)
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata2(end+1:length(ydata1)) = 0;
                mult = ydata1 .* ydata2;
            else
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata1(end+1:length(ydata2)) = 0;
                mult = ydata1 .* ydata2;
            end

            % Graficar la señal resultante en "app.axes_3"
            plot(app.axes_3, mult,'m','LineWidth', 2);

        end

        % Button pushed function: SumarButton
        function SumarButtonPushed(app, event)
            %clear
            cla(app.axes_3);

             % Obtener los datos de la señal graficada en "app.axes"
            linea1 = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata1 = linea1.YData; % Obtener los datos de la señal
            
            % Obtener los datos de la señal graficada en "app.axes_2"
            linea2 = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata2 = linea2.YData; % Obtener los datos de la señal
            
            % Sumar las señales
            if length(ydata1) > length(ydata2)
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata2(end+1:length(ydata1)) = 0;
                suma = ydata1 + ydata2;
            else
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata1(end+1:length(ydata2)) = 0;
                suma = ydata1 + ydata2;
            end
            
            % Graficar la señal resultante en "app.axes_3"
            plot(app.axes_3, suma,'m','LineWidth', 2);

        end

        % Button pushed function: DerivarButton
        function DerivarButtonPushed(app, event)
            %clear
            cla(app.axes_3);
            
            % Obtener los datos de la señal graficada en "app.axes"
            linea = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata = linea.YData; % Obtener los datos de la señal
            
            % Calcular la derivada de la señal
            derivada = diff(ydata);
            
            % Graficar la señal resultante en "app.axes_2"
            plot(app.axes_3, derivada,'m','LineWidth', 2);

        end

        % Button pushed function: DerivarButton_2
        function DerivarButton_2Pushed(app, event)
            %clear
            cla(app.axes_3);
            
            % Obtener los datos de la señal graficada en "app.axes"
            linea = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata = linea.YData; % Obtener los datos de la señal
            
            % Calcular la derivada de la señal
            derivada = diff(ydata);
            
            % Graficar la señal resultante en "app.axes_2"
            plot(app.axes_3, derivada,'m','LineWidth', 2);
        end

        % Button pushed function: IntegrarButton
        function IntegrarButtonPushed(app, event)
            % Obtener los datos de la señal graficada en "app.axes"
            linea = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata = linea.YData; % Obtener los datos de la señal
            
            % Calcular la integral de la señal
            integral = cumtrapz(ydata);
            
            % Graficar la señal resultante en "app.axes_2"
            plot(app.axes_3, integral,'m','LineWidth', 2);

        end

        % Button pushed function: IntegrarButton_2
        function IntegrarButton_2Pushed(app, event)
            % Obtener los datos de la señal graficada en "app.axes"
            linea = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata = linea.YData; % Obtener los datos de la señal
            
            % Calcular la integral de la señal
            integral = cumtrapz(ydata);
            
            % Graficar la señal resultante en "app.axes_2"
            plot(app.axes_3, integral,'m','LineWidth', 2);

        end

        % Button pushed function: btnWindow
        function btnWindowPushed(app, event)
            P5_1
            app.window2.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.window2 = uifigure('Visible', 'off');
            app.window2.Name = 'MATLAB App';
            app.axes = uiaxes(app.window2);
            title(app.axes, 'Gráfica')
            xlabel(app.axes, 'Tiempo (s)')
            ylabel(app.axes, 'Amplitud')
            zlabel(app.axes, 'Z')
            app.axes.YTickLabel = {'-10'; '-5'; '0'; '5'; '10'};
            app.axes_3 = uiaxes(app.window2);
            title(app.axes_3, 'Gráfica')
            xlabel(app.axes_3, 'Tiempo (s)')
            ylabel(app.axes_3, 'Amplitud')
            zlabel(app.axes_3, 'Z')
            app.axes_3.YTickLabel = {'-10'; '-5'; '0'; '5'; '10'};
            app.axes_2 = uiaxes(app.window2);
            title(app.axes_2, 'Gráfica')
            xlabel(app.axes_2, 'Tiempo (s)')
            ylabel(app.axes_2, 'Amplitud')
            zlabel(app.axes_2, 'Z')
            app.axes_2.YTickLabel = {'-10'; '-5'; '0'; '5'; '10'};
            app.groupRep = uibuttongroup(app.window2);
            app.groupRep.Title = 'Representación';
            app.radioCont = uiradiobutton(app.groupRep);
            app.radioCont.Text = 'Continua';
            app.radioCont.Value = true;
            app.radioDisc = uiradiobutton(app.groupRep);
            app.radioDisc.Text = 'Discreta';
            app.btnGraficar = uibutton(app.window2, 'push');
            app.btnGraficar.ButtonPushedFcn = createCallbackFcn(app, @btnGraficarPushed, true);
            app.btnGraficar.Text = 'Graficar';
            app.SealDropDownLabel = uilabel(app.window2);
            app.SealDropDownLabel.Text = 'Señal:';
            app.downSignal = uidropdown(app.window2);
            app.downSignal.Items = {'Seleccionar', 'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalón Unitario', 'Impulso Unitario'};
            app.downSignal.ItemsData = [1 2 3 4 5 6 7 8];
            app.downSignal.Value = 1;
            app.EscalamientoAmplitudLabel = uilabel(app.window2);
            app.EscalamientoAmplitudLabel.Text = {'Escalamiento '; 'Amplitud'};
            app.sliderAmplitud = uislider(app.window2);
            app.sliderAmplitud.Limits = [0 10];
            app.sliderAmplitud.Value = 4;
            app.FrecuenciaLabel = uilabel(app.window2);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.sliderFrec = uislider(app.window2);
            app.sliderFrec.Limits = [0 10];
            app.sliderFrec.Value = 1;
            app.TiempoMinEditFieldLabel = uilabel(app.window2);
            app.TiempoMinEditFieldLabel.Text = 'Tiempo Min:';
            app.txtTMin = uieditfield(app.window2, 'text');
            app.txtTMin.Value = '0';
            app.TiempoMxLabel = uilabel(app.window2);
            app.TiempoMxLabel.Text = 'Tiempo Máx:';
            app.txtTMax = uieditfield(app.window2, 'text');
            app.txtTMax.Value = '5';
            app.groupRep_2 = uibuttongroup(app.window2);
            app.groupRep_2.Title = 'Representación';
            app.radioCont_2 = uiradiobutton(app.groupRep_2);
            app.radioCont_2.Text = 'Continua';
            app.radioCont_2.Value = true;
            app.radioDisc_2 = uiradiobutton(app.groupRep_2);
            app.radioDisc_2.Text = 'Discreta';
            app.btnGraficar_2 = uibutton(app.window2, 'push');
            app.btnGraficar_2.ButtonPushedFcn = createCallbackFcn(app, @btnGraficar_2ButtonPushed, true);
            app.btnGraficar_2.Text = 'Graficar';
            app.downSignal_2 = uidropdown(app.window2);
            app.downSignal_2.Items = {'Seleccionar', 'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalón Unitario', 'Impulso Unitario'};
            app.downSignal_2.ItemsData = [1 2 3 4 5 6 7 8];
            app.downSignal_2.Value = 1;
            app.sliderAmplitud_2 = uislider(app.window2);
            app.sliderAmplitud_2.Limits = [0 10];
            app.sliderAmplitud_2.Value = 1;
            app.sliderFrec_2 = uislider(app.window2);
            app.sliderFrec_2.Limits = [0 10];
            app.sliderFrec_2.Value = 1;
            app.txtTMin_2 = uieditfield(app.window2, 'text');
            app.txtTMin_2.Value = '0';
            app.txtTMax_2 = uieditfield(app.window2, 'text');
            app.txtTMax_2.Value = '5';
            app.SumarButton = uibutton(app.window2, 'push');
            app.SumarButton.ButtonPushedFcn = createCallbackFcn(app, @SumarButtonPushed, true);
            app.SumarButton.Text = 'Sumar';
            app.MultiplicarButton = uibutton(app.window2, 'push');
            app.MultiplicarButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicarButtonPushed, true);
            app.MultiplicarButton.Text = 'Multiplicar';
            app.EscalamientoAmplitudLabel_2 = uilabel(app.window2);
            app.EscalamientoAmplitudLabel_2.Text = {'Escalamiento'; 'Amplitud'};
            app.DerivarButton = uibutton(app.window2, 'push');
            app.DerivarButton.ButtonPushedFcn = createCallbackFcn(app, @DerivarButtonPushed, true);
            app.DerivarButton.Text = 'Derivar';
            app.IntegrarButton = uibutton(app.window2, 'push');
            app.IntegrarButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrarButtonPushed, true);
            app.IntegrarButton.Text = 'Integrar';
            app.DerivarButton_2 = uibutton(app.window2, 'push');
            app.DerivarButton_2.ButtonPushedFcn = createCallbackFcn(app, @DerivarButton_2Pushed, true);
            app.DerivarButton_2.Text = 'Derivar';
            app.IntegrarButton_2 = uibutton(app.window2, 'push');
            app.IntegrarButton_2.ButtonPushedFcn = createCallbackFcn(app, @IntegrarButton_2Pushed, true);
            app.IntegrarButton_2.Text = 'Integrar';
            app.FrecuenciaLabel_2 = uilabel(app.window2);
            app.FrecuenciaLabel_2.Text = 'Frecuencia';
            app.btnWindow = uibutton(app.window2, 'push');
            app.btnWindow.ButtonPushedFcn = createCallbackFcn(app, @btnWindowPushed, true);
            app.btnWindow.Text = 'Variable Independiente';
            app.txtTitulo = uilabel(app.window2);
            app.txtTitulo.Text = 'Operaciones sobre la variable Dependiente';
            app.ReflexinLabel = uilabel(app.window2);
            app.ReflexinLabel.Text = 'Reflexión';
            app.cboxReflex = uicheckbox(app.window2);
            app.cboxReflex.Text = '';
            app.EscalamientodeTiempoLabel = uilabel(app.window2);
            app.EscalamientodeTiempoLabel.Text = 'Escalamiento de Tiempo:';
            app.txtEscala = uieditfield(app.window2, 'text');
            app.txtEscala.Value = '1';
            app.EscalamientodeTiempoLabel_2 = uilabel(app.window2);
            app.EscalamientodeTiempoLabel_2.Text = 'Desplazamiento de Tiempo: t + ';
            app.txtDespla = uieditfield(app.window2, 'text');
            app.txtDespla.Value = '0';
            app.ReflexinLabel_2 = uilabel(app.window2);
            app.ReflexinLabel_2.Text = 'Reflexión';
            app.cboxReflex_2 = uicheckbox(app.window2);
            app.cboxReflex_2.Text = '';
            app.EscalamientodeTiempoLabel_3 = uilabel(app.window2);
            app.EscalamientodeTiempoLabel_3.Text = 'Desplazamiento de Tiempo: t + ';
            app.txtDespla_2 = uieditfield(app.window2, 'text');
            app.txtDespla_2.Value = '0';
            app.EscalamientodeTiempoLabel_4 = uilabel(app.window2);
            app.EscalamientodeTiempoLabel_4.Text = 'Escalamiento de Tiempo:';
            app.txtEscala_2 = uieditfield(app.window2, 'text');
            app.txtEscala_2.Value = '1';
            % Show the figure after all components are created
            app.window2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P5_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.window2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.window2)
        end
    end
end