classdef P9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        window2                      matlab.ui.Figure
        Canal3Panel                  matlab.ui.container.Panel
        SimilitudLabel               matlab.ui.control.Label
        txtSimilitud                 matlab.ui.control.TextArea
        btnCorrelacion               matlab.ui.control.Button
        ConvolucinButton             matlab.ui.control.Button
        MultiplicarButton            matlab.ui.control.Button
        SumarButton                  matlab.ui.control.Button
        Canal2Panel                  matlab.ui.container.Panel
        DerivarButton_2              matlab.ui.control.Button
        FrecuenciaLabel_2            matlab.ui.control.Label
        AmplitudLabel_2              matlab.ui.control.Label
        sliderFrec_2                 matlab.ui.control.Slider
        sliderAmplitud_2             matlab.ui.control.Slider
        IntegrarButton_2             matlab.ui.control.Button
        btnGraficar_2                matlab.ui.control.Button
        cboxReflex_2                 matlab.ui.control.CheckBox
        ReflexinLabel_2              matlab.ui.control.Label
        txtEscala_2                  matlab.ui.control.EditField
        EscalamientodeTiempoLabel_6  matlab.ui.control.Label
        txtDespla_2                  matlab.ui.control.EditField
        EscalamientodeTiempoLabel_5  matlab.ui.control.Label
        txtTMax_2                    matlab.ui.control.EditField
        TiempoMxLabel_3              matlab.ui.control.Label
        txtTMin_2                    matlab.ui.control.EditField
        TiempoMinEditFieldLabel_3    matlab.ui.control.Label
        downSignal_2                 matlab.ui.control.DropDown
        groupRep_2                   matlab.ui.container.ButtonGroup
        radioDisc_2                  matlab.ui.control.RadioButton
        radioCont_2                  matlab.ui.control.RadioButton
        Canal1Panel                  matlab.ui.container.Panel
        DerivarButton                matlab.ui.control.Button
        btnGraficar                  matlab.ui.control.Button
        IntegrarButton               matlab.ui.control.Button
        cboxReflex                   matlab.ui.control.CheckBox
        ReflexinLabel                matlab.ui.control.Label
        FrecuenciaLabel              matlab.ui.control.Label
        AmplitudLabel                matlab.ui.control.Label
        sliderFrec                   matlab.ui.control.Slider
        sliderAmplitud               matlab.ui.control.Slider
        txtEscala                    matlab.ui.control.EditField
        EscalamientodeTiempoLabel_4  matlab.ui.control.Label
        txtDespla                    matlab.ui.control.EditField
        EscalamientodeTiempoLabel_3  matlab.ui.control.Label
        txtTMax                      matlab.ui.control.EditField
        TiempoMxLabel_2              matlab.ui.control.Label
        txtTMin                      matlab.ui.control.EditField
        TiempoMinEditFieldLabel_2    matlab.ui.control.Label
        downSignal                   matlab.ui.control.DropDown
        groupRep                     matlab.ui.container.ButtonGroup
        radioDisc                    matlab.ui.control.RadioButton
        radioCont                    matlab.ui.control.RadioButton
        axes_2                       matlab.ui.control.UIAxes
        axes_3                       matlab.ui.control.UIAxes
        axes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.axes.Color = [0 0 0];  % Establecer el color de fondo en negro
            app.axes.GridColor = [1 1 1];  % Establecer el color de la cuadrícula en blanco

            app.axes_2.Color = [0 0 0];  % Establecer el color de fondo en negro
            app.axes_2.GridColor = [1 1 1];  % Establecer el color de la cuadrícula en blanco

            app.axes_3.Color = [0 0 0];  % Establecer el color de fondo en negro
            app.axes_3.GridColor = [1 1 1];  % Establecer el color de la cuadrícula en blanco
        end

        % Button pushed function: btnGraficar
        function btnGraficarButtonPushed(app, event)
            signal = app.downSignal.Value;
            amplitud = app.sliderAmplitud.Value;
            frecuencia = app.sliderFrec.Value;

            tmin = str2double(get(app.txtTMin, 'Value'));
            tmax = str2double(get(app.txtTMax, 'Value'));

            escalamiento = str2double(get(app.txtEscala, 'Value'));
            reflexion = app.cboxReflex.Value;
            desplazamiento = str2double(get(app.txtDespla, 'Value'));    

            switch signal
                case 2 %SENOIDAL
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 50;
                    end
    
                    t = linspace(tmin, tmax, puntoTiempo); 
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                        
                    x = 2 * pi * frecuencia * t;
                        
                    if reflexion == 1 
                        aux = escalamiento * (x + desplazamiento);
                        y2 = amplitud * sin( aux * -1); 
                    else
                        y2 = amplitud * sin( escalamiento * (x + desplazamiento)); 
                    end

                case 3 %EXPONENCIAL
                    Fs = 10; 
                    t = tmin:1/Fs:tmax;
                    t2 = tmin+desplazamiento:1/Fs:tmax+desplazamiento; 
    
                    if reflexion == 1 
	                    aux = amplitud.^( escalamiento * (t + desplazamiento));
	                    y2 = fliplr(aux);
                    else
                        y2 = amplitud.^ (escalamiento * (t + desplazamiento));    
                    end

                case 4 %RAMPA
                    if app.radioCont.Value == 1
                        puntoTiempo = 10000;
                    else
                        puntoTiempo = 5;
                    end
    
                    Fs = puntoTiempo;
                
                    ta = 0:1/Fs:tmax;
                    tb = tmax:1/Fs:tmax*2;
                    %t = [ta,tb];
                    t2= [escalamiento * (ta + desplazamiento), escalamiento * (tb + desplazamiento)];
                
                    r = linspace(0, amplitud, length(ta));
                
                    if reflexion == 1
                        t2= [(escalamiento * (ta + desplazamiento))*-1, (escalamiento * (tb + desplazamiento))*-1];
                    end
                
                    y2 = amplitud.*[zeros(1,length(ta)),r];

                case 5 %CUADRADA
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 50;
                    end
    
                    duty = 50;
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                    
                    x = 2*pi*frecuencia*t;
                    
                    if reflexion == 1
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * square( aux * -1, duty); 
                    else
                        y2 = amplitud * square( escalamiento * (x + desplazamiento),duty);
                    end                      

                case 6 %TRIANGULAR
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 50;
                    end
                    
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                                        
                    x = 2*pi*t;
                                        
                    if reflexion == 1 
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * sawtooth( aux * -1,0.5);
                    else
                        y2 = amplitud * sawtooth( escalamiento * (x + desplazamiento),0.5); 
                    end

                case 7 %ESCALON UNITARIO
                    if app.radioCont.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 50;
                    end

                    t2 = linspace(tmin+ desplazamiento, tmax+ desplazamiento, puntoTiempo);
                        
                    if reflexion == 1
                        aux = escalamiento * (heaviside(t2) );
                        y2 = aux * -1; 
                    else
                        y2 = escalamiento * (heaviside(t2) );
                    end

                case 8 %IMPULSO UNITARIO
                    t2 = (tmin+ desplazamiento): (tmax+ desplazamiento);

                    y2 = (amplitud*escalamiento).*[zeros(1,3),ones(1,1),zeros(1,2)];

                    app.radioCont.Value = 0;
                    app.radioDisc.Value = 1;
            end

            if app.radioCont.Value == 1
                plot(app.axes,t2, y2,'y','LineWidth', 2); 
            elseif app.radioDisc.Value == 1
                stem(app.axes,t2, y2,'y','LineWidth', 2); 
            end     
            
        end

        % Button pushed function: btnGraficar_2
        function btnGraficar_2ButtonPushed(app, event)
            signal = app.downSignal_2.Value;
            amplitud = app.sliderAmplitud_2.Value;
            frecuencia = app.sliderFrec_2.Value;

            tmin = str2double(get(app.txtTMin_2, 'Value'));
            tmax = str2double(get(app.txtTMax_2, 'Value'));

            escalamiento = str2double(get(app.txtEscala_2, 'Value'));
            reflexion = app.cboxReflex_2.Value;
            desplazamiento = str2double(get(app.txtDespla_2, 'Value'));    

            switch signal
                case 2 %SENOIDAL
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 50;
                    end
    
                    t = linspace(tmin, tmax, puntoTiempo); 
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                        
                    x = 2 * pi * frecuencia * t;
                        
                    if reflexion == 1 
                        aux = escalamiento * (x + desplazamiento);
                        y2 = amplitud * sin( aux * -1); 
                    else
                        y2 = amplitud * sin( escalamiento * (x + desplazamiento)); 
                    end

                case 3 %EXPONENCIAL
                    Fs = 10; 
                    t = tmin:1/Fs:tmax;
                    t2 = tmin+desplazamiento:1/Fs:tmax+desplazamiento; 
    
                    if reflexion == 1 
	                    aux = amplitud.^( escalamiento * (t + desplazamiento));
	                    y2 = fliplr(aux);
                    else
                        y2 = amplitud.^ (escalamiento * (t + desplazamiento));    
                    end

                case 4 %RAMPA
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 10000;
                    else
                        puntoTiempo = 5;
                    end
    
                    Fs = puntoTiempo;
                
                    ta = 0:1/Fs:tmax;
                    tb = tmax:1/Fs:tmax*2;
                    %t = [ta,tb];
                    t2= [escalamiento * (ta + desplazamiento), escalamiento * (tb + desplazamiento)];
                
                    r = linspace(0, amplitud, length(ta));
                
                    if reflexion == 1
                        t2= [(escalamiento * (ta + desplazamiento))*-1, (escalamiento * (tb + desplazamiento))*-1];
                    end
                
                    y2 = amplitud.*[zeros(1,length(ta)),r];

                case 5 %CUADRADA
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 50;
                    end
    
                    duty = 50;
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                    
                    x = 2*pi*frecuencia*t;
                    
                    if reflexion == 1
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * square( aux * -1, duty); 
                    else
                        y2 = amplitud * square( escalamiento * (x + desplazamiento),duty);
                    end                      

                case 6 %TRIANGULAR
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 50;
                    end
                    
                    t = linspace(tmin, tmax, puntoTiempo);
                    t2 = linspace(tmin+desplazamiento , tmax+desplazamiento, puntoTiempo);
                                        
                    x = 2*pi*t;
                                        
                    if reflexion == 1 
                        aux = escalamiento * (x + desplazamiento);
	                    y2 = amplitud * sawtooth( aux * -1,0.5);
                    else
                        y2 = amplitud * sawtooth( escalamiento * (x + desplazamiento),0.5); 
                    end

                case 7 %ESCALON UNITARIO
                    if app.radioCont_2.Value == 1
                        puntoTiempo = 1000;
                    else
                        puntoTiempo = 50;
                    end

                    t2 = linspace(tmin+ desplazamiento, tmax+ desplazamiento, puntoTiempo);
                        
                    if reflexion == 1
                        aux = escalamiento * (heaviside(t2) );
                        y2 = aux * -1; 
                    else
                        y2 = escalamiento * (heaviside(t2) );
                    end

                case 8 %IMPULSO UNITARIO
                    t2 = (tmin+ desplazamiento): (tmax+ desplazamiento);

                    y2 = (amplitud*escalamiento).*[zeros(1,3),ones(1,1),zeros(1,2)];
            end

            if app.radioCont_2.Value == 1
                plot(app.axes_2,t2, y2,'c','LineWidth', 2); 
            elseif app.radioDisc_2.Value == 1
                stem(app.axes_2,t2, y2,'c','LineWidth', 2); 
            end  
        end

        % Button pushed function: DerivarButton
        function DerivarButtonPushed(app, event)
            %clear
            cla(app.axes_3);
            
            % Obtener los datos de la señal graficada en "app.axes"
            linea = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata = linea.YData; % Obtener los datos de la señal
            
            % Calcular la derivada de la señal
            derivada = diff(ydata);
            
            % Graficar la señal resultante en "app.axes_2"
            plot(app.axes_3, derivada,'g','LineWidth', 2);
        end

        % Button pushed function: DerivarButton_2
        function DerivarButton_2Pushed(app, event)
            %clear
            cla(app.axes_3);
            
            % Obtener los datos de la señal graficada en "app.axes"
            linea = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata = linea.YData; % Obtener los datos de la señal
            
            % Calcular la derivada de la señal
            derivada = diff(ydata);
            
            % Graficar la señal resultante en "app.axes_2"
            plot(app.axes_3, derivada,'g','LineWidth', 2);
        end

        % Button pushed function: IntegrarButton
        function IntegrarButtonPushed(app, event)
            % Obtener los datos de la señal graficada en "app.axes"
            linea = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata = linea.YData; % Obtener los datos de la señal
            
            % Calcular la integral de la señal
            integral = cumtrapz(ydata);
            
            % Graficar la señal resultante en "app.axes_2"
            plot(app.axes_3, integral,'g','LineWidth', 2);

        end

        % Button pushed function: IntegrarButton_2
        function IntegrarButton_2Pushed(app, event)
            % Obtener los datos de la señal graficada en "app.axes"
            linea = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata = linea.YData; % Obtener los datos de la señal
            
            % Calcular la integral de la señal
            integral = cumtrapz(ydata);
            
            % Graficar la señal resultante en "app.axes_2"
            plot(app.axes_3, integral,'g','LineWidth', 2);

        end

        % Button pushed function: SumarButton
        function SumarButtonPushed(app, event)
            %clear
            
            cla(app.axes_3);
            if app.radioCont.Value == 1
                app.radioCont_2.Value = 1;
                app.radioDisc_2.Value = 0;
            elseif app.radioDisc.Value == 1
                app.radioCont_2.Value = 0;
                app.radioDisc_2.Value = 1;
            end

             % Obtener los datos de la señal graficada en "app.axes"
            linea1 = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata1 = linea1.YData; % Obtener los datos de la señal
            
            % Obtener los datos de la señal graficada en "app.axes_2"
            linea2 = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata2 = linea2.YData; % Obtener los datos de la señal
            
            % Sumar las señales
            if length(ydata1) > length(ydata2)
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata2(end+1:length(ydata1)) = 0;
                suma = ydata1 + ydata2;
            else
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata1(end+1:length(ydata2)) = 0;
                suma = ydata1 + ydata2;
            end
            
            if app.radioCont.Value == 1
                plot(app.axes_3, suma,'g','LineWidth', 2);
            elseif app.radioDisc.Value == 1
                stem(app.axes_3, suma,'g','LineWidth', 2);
            end

        end

        % Button pushed function: MultiplicarButton
        function MultiplicarButtonPushed(app, event)
            %clear
            if app.radioCont.Value == 1
                app.radioCont_2.Value = 1;
                app.radioDisc_2.Value = 0;
            elseif app.radioDisc.Value == 1
                app.radioCont_2.Value = 0;
                app.radioDisc_2.Value = 1;
            end
            cla(app.axes_3);
            
            % Obtener los datos de la señal graficada en "app.axes"
            linea1 = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata1 = linea1.YData; % Obtener los datos de la señal
            
            % Obtener los datos de la señal graficada en "app.axes_2"
            linea2 = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata2 = linea2.YData; % Obtener los datos de la señal
            
            % Multiplicar las señales
            if length(ydata1) > length(ydata2)
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata2(end+1:length(ydata1)) = 0;
                mult = ydata1 .* ydata2;
            else
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata1(end+1:length(ydata2)) = 0;
                mult = ydata1 .* ydata2;
            end

            if app.radioCont.Value == 1
                plot(app.axes_3, mult,'g','LineWidth', 2);
            elseif app.radioDisc.Value == 1
                stem(app.axes_3, mult,'g','LineWidth', 2);
            end

        end

        % Button pushed function: ConvolucinButton
        function ConvolucinButtonPushed(app, event)
            %clear

            if app.radioCont.Value == 1
                app.radioCont_2.Value = 1;
                app.radioDisc_2.Value = 0;
            elseif app.radioDisc.Value == 1
                app.radioCont_2.Value = 0;
                app.radioDisc_2.Value = 1;
            end
            cla(app.axes_3);
            
            % Obtener los datos de la señal graficada en "app.axes"
            linea1 = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata1 = linea1.YData; % Obtener los datos de la señal
            
            % Obtener los datos de la señal graficada en "app.axes_2"
            linea2 = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata2 = linea2.YData; % Obtener los datos de la señal
            
            % Multiplicar las señales
            if length(ydata1) > length(ydata2)
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata2(end+1:length(ydata1)) = 0;
                convolucion = conv(ydata1,ydata2,"full");
            else
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata1(end+1:length(ydata2)) = 0;
                convolucion = conv(ydata1,ydata2,"full");
            end
            
            
            if app.radioCont.Value == 1
                plot(app.axes_3, convolucion,'g','LineWidth', 2);
            elseif app.radioDisc.Value == 1
                stem(app.axes_3, convolucion,'g','LineWidth', 2);
            end
            
        end

        % Button pushed function: btnCorrelacion
        function btnCorrelacionButtonPushed(app, event)
            %clear

            if app.radioCont.Value == 1
                app.radioCont_2.Value = 1;
                app.radioDisc_2.Value = 0;
            elseif app.radioDisc.Value == 1
                app.radioCont_2.Value = 0;
                app.radioDisc_2.Value = 1;
            end
            cla(app.axes_3);
            
            % Obtener los datos de la señal graficada en "app.axes"
            linea1 = app.axes.Children; % Obtener el objeto de la clase "Line"
            ydata1 = linea1.YData; % Obtener los datos de la señal
            
            % Obtener los datos de la señal graficada en "app.axes_2"
            linea2 = app.axes_2.Children; % Obtener el objeto de la clase "Line"
            ydata2 = linea2.YData; % Obtener los datos de la señal
            
            if length(ydata1) > length(ydata2)
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata2(end+1:length(ydata1)) = 0;
                correlacion = xcorr(ydata1,ydata2,'normalized');
            else
                % Rellenar la señal más corta con ceros al final para igualar la longitud
                ydata1(end+1:length(ydata2)) = 0;
                correlacion = xcorr(ydata1,ydata2,'normalized');
            end         

            % Restar el valor mínimo para trasladar los valores a un rango no negativo
            corr_norm = correlacion - min(correlacion);
            
            % Escalar los valores al rango de 0 a 1 dividiendo por el valor máximo
            corr_norm = corr_norm / max(corr_norm);

            % Reducción del número de muestras para la visualización
            paso_submuestreo = 1; 
            corr_norm_red = corr_norm(1:paso_submuestreo:end);
            
            % Trazar el resultado reducido
            stem(app.axes_3, corr_norm_red, 'g', 'LineWidth', 2);

            %similitud = max(correlacion);    

            [maximo, indice] = max(correlacion);   

            if maximo == 1
                app.txtSimilitud.Value = sprintf('Las señales son identicas.\nValor máximo = %f\nIndice = %d', maximo, indice);
            elseif maximo <1 && maximo>=0.6
                app.txtSimilitud.Value = sprintf('Las señales son similares.\nValor máximo = %f\nIndice = %d', maximo, indice);
            else
                app.txtSimilitud.Value = sprintf('Las señales son diferentes.\nValor máximo = %f\nIndice = %d', maximo, indice);
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.window2 = uifigure('Visible', 'off');
            app.window2.Name = 'MATLAB App';
            app.axes = uiaxes(app.window2);
            title(app.axes, 'Canal 1')
            xlabel(app.axes, 'Tiempo (s)')
            ylabel(app.axes, 'Amplitud')
            zlabel(app.axes, 'Z')
            app.axes.YTickLabel = {'0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.axes_3 = uiaxes(app.window2);
            title(app.axes_3, 'Canal 3')
            xlabel(app.axes_3, 'Tiempo (s)')
            ylabel(app.axes_3, 'Amplitud')
            zlabel(app.axes_3, 'Z')
            app.axes_2 = uiaxes(app.window2);
            title(app.axes_2, 'Canal 2')
            xlabel(app.axes_2, 'Tiempo (s)')
            ylabel(app.axes_2, 'Amplitud')
            zlabel(app.axes_2, 'Z')
            app.Canal1Panel = uipanel(app.window2);
            app.Canal1Panel.Title = 'Canal 1';
            app.groupRep = uibuttongroup(app.Canal1Panel);
            app.groupRep.Title = 'Representación';
            app.radioCont = uiradiobutton(app.groupRep);
            app.radioCont.Text = 'Continua';
            app.radioDisc = uiradiobutton(app.groupRep);
            app.radioDisc.Text = 'Discreta';
            app.radioDisc.Value = true;
            app.downSignal = uidropdown(app.Canal1Panel);
            app.downSignal.Items = {'Seleccionar', 'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalón Unitario', 'Impulso Unitario'};
            app.downSignal.ItemsData = [1 2 3 4 5 6 7 8];
            app.downSignal.Value = 1;
            app.TiempoMinEditFieldLabel_2 = uilabel(app.Canal1Panel);
            app.TiempoMinEditFieldLabel_2.Text = 'Tiempo Min:';
            app.txtTMin = uieditfield(app.Canal1Panel, 'text');
            app.txtTMin.Value = '0';
            app.TiempoMxLabel_2 = uilabel(app.Canal1Panel);
            app.TiempoMxLabel_2.Text = 'Tiempo Máx:';
            app.txtTMax = uieditfield(app.Canal1Panel, 'text');
            app.txtTMax.Value = '5';
            app.EscalamientodeTiempoLabel_3 = uilabel(app.Canal1Panel);
            app.EscalamientodeTiempoLabel_3.Text = 'Desplazamiento : t + ';
            app.txtDespla = uieditfield(app.Canal1Panel, 'text');
            app.txtDespla.Value = '0';
            app.EscalamientodeTiempoLabel_4 = uilabel(app.Canal1Panel);
            app.EscalamientodeTiempoLabel_4.Text = 'Escalamiento:';
            app.txtEscala = uieditfield(app.Canal1Panel, 'text');
            app.txtEscala.Value = '1';
            app.sliderAmplitud = uislider(app.Canal1Panel);
            app.sliderAmplitud.Limits = [0 10];
            app.sliderAmplitud.Value = 4;
            app.sliderFrec = uislider(app.Canal1Panel);
            app.sliderFrec.Limits = [0 10];
            app.sliderFrec.Value = 1;
            app.AmplitudLabel = uilabel(app.Canal1Panel);
            app.AmplitudLabel.Text = 'Amplitud';
            app.FrecuenciaLabel = uilabel(app.Canal1Panel);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.ReflexinLabel = uilabel(app.Canal1Panel);
            app.ReflexinLabel.Text = 'Reflexión';
            app.cboxReflex = uicheckbox(app.Canal1Panel);
            app.cboxReflex.Text = '';
            app.IntegrarButton = uibutton(app.Canal1Panel, 'push');
            app.IntegrarButton.ButtonPushedFcn = createCallbackFcn(app, @IntegrarButtonPushed, true);
            app.IntegrarButton.Text = 'Integrar';
            app.btnGraficar = uibutton(app.Canal1Panel, 'push');
            app.btnGraficar.ButtonPushedFcn = createCallbackFcn(app, @btnGraficarButtonPushed, true);
            app.btnGraficar.Text = 'Graficar';
            app.DerivarButton = uibutton(app.Canal1Panel, 'push');
            app.DerivarButton.ButtonPushedFcn = createCallbackFcn(app, @DerivarButtonPushed, true);
            app.DerivarButton.Text = 'Derivar';
            app.Canal2Panel = uipanel(app.window2);
            app.Canal2Panel.Title = 'Canal 2';
            app.groupRep_2 = uibuttongroup(app.Canal2Panel);
            app.groupRep_2.Title = 'Representación';
            app.radioCont_2 = uiradiobutton(app.groupRep_2);
            app.radioCont_2.Text = 'Continua';
            app.radioDisc_2 = uiradiobutton(app.groupRep_2);
            app.radioDisc_2.Text = 'Discreta';
            app.radioDisc_2.Value = true;
            app.downSignal_2 = uidropdown(app.Canal2Panel);
            app.downSignal_2.Items = {'Seleccionar', 'Senoidal', 'Exponencial', 'Rampa', 'Cuadrada', 'Triangular', 'Escalón Unitario', 'Impulso Unitario'};
            app.downSignal_2.ItemsData = [1 2 3 4 5 6 7 8];
            app.downSignal_2.Value = 1;
            app.TiempoMinEditFieldLabel_3 = uilabel(app.Canal2Panel);
            app.TiempoMinEditFieldLabel_3.Text = 'Tiempo Min:';
            app.txtTMin_2 = uieditfield(app.Canal2Panel, 'text');
            app.txtTMin_2.Value = '0';
            app.TiempoMxLabel_3 = uilabel(app.Canal2Panel);
            app.TiempoMxLabel_3.Text = 'Tiempo Máx:';
            app.txtTMax_2 = uieditfield(app.Canal2Panel, 'text');
            app.txtTMax_2.Value = '5';
            app.EscalamientodeTiempoLabel_5 = uilabel(app.Canal2Panel);
            app.EscalamientodeTiempoLabel_5.Text = 'Desplazamiento : t + ';
            app.txtDespla_2 = uieditfield(app.Canal2Panel, 'text');
            app.txtDespla_2.Value = '0';
            app.EscalamientodeTiempoLabel_6 = uilabel(app.Canal2Panel);
            app.EscalamientodeTiempoLabel_6.Text = 'Escalamiento:';
            app.txtEscala_2 = uieditfield(app.Canal2Panel, 'text');
            app.txtEscala_2.Value = '1';
            app.ReflexinLabel_2 = uilabel(app.Canal2Panel);
            app.ReflexinLabel_2.Text = 'Reflexión';
            app.cboxReflex_2 = uicheckbox(app.Canal2Panel);
            app.cboxReflex_2.Text = '';
            app.btnGraficar_2 = uibutton(app.Canal2Panel, 'push');
            app.btnGraficar_2.ButtonPushedFcn = createCallbackFcn(app, @btnGraficar_2ButtonPushed, true);
            app.btnGraficar_2.Text = 'Graficar';
            app.IntegrarButton_2 = uibutton(app.Canal2Panel, 'push');
            app.IntegrarButton_2.ButtonPushedFcn = createCallbackFcn(app, @IntegrarButton_2Pushed, true);
            app.IntegrarButton_2.Text = 'Integrar';
            app.sliderAmplitud_2 = uislider(app.Canal2Panel);
            app.sliderAmplitud_2.Limits = [0 10];
            app.sliderAmplitud_2.Value = 4;
            app.sliderFrec_2 = uislider(app.Canal2Panel);
            app.sliderFrec_2.Limits = [0 10];
            app.sliderFrec_2.Value = 1;
            app.AmplitudLabel_2 = uilabel(app.Canal2Panel);
            app.AmplitudLabel_2.Text = 'Amplitud';
            app.FrecuenciaLabel_2 = uilabel(app.Canal2Panel);
            app.FrecuenciaLabel_2.Text = 'Frecuencia';
            app.DerivarButton_2 = uibutton(app.Canal2Panel, 'push');
            app.DerivarButton_2.ButtonPushedFcn = createCallbackFcn(app, @DerivarButton_2Pushed, true);
            app.DerivarButton_2.Text = 'Derivar';
            app.Canal3Panel = uipanel(app.window2);
            app.Canal3Panel.Title = 'Canal 3';
            app.SumarButton = uibutton(app.Canal3Panel, 'push');
            app.SumarButton.ButtonPushedFcn = createCallbackFcn(app, @SumarButtonPushed, true);
            app.SumarButton.Text = 'Sumar';
            app.MultiplicarButton = uibutton(app.Canal3Panel, 'push');
            app.MultiplicarButton.ButtonPushedFcn = createCallbackFcn(app, @MultiplicarButtonPushed, true);
            app.MultiplicarButton.Text = 'Multiplicar';
            app.ConvolucinButton = uibutton(app.Canal3Panel, 'push');
            app.ConvolucinButton.ButtonPushedFcn = createCallbackFcn(app, @ConvolucinButtonPushed, true);
            app.ConvolucinButton.Text = 'Convolución';
            app.btnCorrelacion = uibutton(app.Canal3Panel, 'push');
            app.btnCorrelacion.ButtonPushedFcn = createCallbackFcn(app, @btnCorrelacionButtonPushed, true);
            app.btnCorrelacion.Text = 'Correlacion';
            app.txtSimilitud = uitextarea(app.Canal3Panel);
            app.SimilitudLabel = uilabel(app.Canal3Panel);
            app.SimilitudLabel.Text = 'Similitud:';
            % Show the figure after all components are created
            app.window2.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.window2)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.window2)
        end
    end
end